classdef hyperdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HyperDataUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AnisotropicTab                  matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        QualityoffitPanel               matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        UITable_qof                     matlab.ui.control.Table
        OptimizeButton                  matlab.ui.control.Button
        PlotselectionPanel              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PlotButton                      matlab.ui.control.Button
        sixthdataButton                 matlab.ui.control.StateButton
        fifthdataButton                 matlab.ui.control.StateButton
        fourthdataButton                matlab.ui.control.StateButton
        thirddataButton                 matlab.ui.control.StateButton
        seconddataButton                matlab.ui.control.StateButton
        firstdataButton                 matlab.ui.control.StateButton
        Image_2                         matlab.ui.control.Image
        FiberdirectionsPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DirectionofFiber3EditField_3    matlab.ui.control.NumericEditField
        DirectionofFiber3EditField_2    matlab.ui.control.NumericEditField
        Directionoffiber3EditField      matlab.ui.control.NumericEditField
        Directionoffiber3EditFieldLabel  matlab.ui.control.Label
        DirectionofFiber2EditField_3    matlab.ui.control.NumericEditField
        DirectionofFiber2EditField_2    matlab.ui.control.NumericEditField
        Directionoffiber2EditField      matlab.ui.control.NumericEditField
        Directionoffiber2EditFieldLabel  matlab.ui.control.Label
        DirectionofFiber1EditField_3    matlab.ui.control.NumericEditField
        DirectionofFiber1EditField_2    matlab.ui.control.NumericEditField
        Directionoffiber1EditField      matlab.ui.control.NumericEditField
        Directionoffiber1EditFieldLabel  matlab.ui.control.Label
        TensionOnlyPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        TensiononlyconditionSwitch      matlab.ui.control.RockerSwitch
        TensiononlyconditionSwitchLabel  matlab.ui.control.Label
        DispersionModelButtonGroup      matlab.ui.container.ButtonGroup
        ioButton                        matlab.ui.control.RadioButton
        fsButton                        matlab.ui.control.RadioButton
        DispersionmodelPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        kappa_3EditField                matlab.ui.control.NumericEditField
        kappa_3Label                    matlab.ui.control.Label
        kappa_opEditField               matlab.ui.control.NumericEditField
        kappa_opLabel                   matlab.ui.control.Label
        kappa_2EditField                matlab.ui.control.NumericEditField
        kappa_2Label                    matlab.ui.control.Label
        kappa_ipEditField               matlab.ui.control.NumericEditField
        kappa_ipLabel                   matlab.ui.control.Label
        kappa_1EditField                matlab.ui.control.NumericEditField
        kappa_1Label                    matlab.ui.control.Label
        SystemparametersPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        NumberoffreeenergyfunctionsSpinner  matlab.ui.control.Spinner
        NumberoffreeenergyfunctionsSpinnerLabel  matlab.ui.control.Label
        DegreeofBsplinesSpinner         matlab.ui.control.Spinner
        DegreeofBsplinesSpinnerLabel    matlab.ui.control.Label
        NumberofcontrolpointsSpinner    matlab.ui.control.Spinner
        NumberofcontrolpointsSpinnerLabel  matlab.ui.control.Label
        InputfilesPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SelectfileButton_6              matlab.ui.control.Button
        EditField_6                     matlab.ui.control.EditField
        nsLabel                         matlab.ui.control.Label
        SelectfileButton_5              matlab.ui.control.Button
        EditField_5                     matlab.ui.control.EditField
        snLabel                         matlab.ui.control.Label
        SelectfileButton_4              matlab.ui.control.Button
        EditField_4                     matlab.ui.control.EditField
        nfLabel                         matlab.ui.control.Label
        SelectfileButton_3              matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        fnLabel                         matlab.ui.control.Label
        SelectfileButton_2              matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        sfLabel                         matlab.ui.control.Label
        SelectfileButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        fsLabel                         matlab.ui.control.Label
        TypeofexperimentDropDown        matlab.ui.control.DropDown
        TypeofexperimentDropDownLabel   matlab.ui.control.Label
        IsotropicTab                    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        FormulationtypePanel            matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        FormulationTypeListBox          matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        PolyconvexitySwitch             matlab.ui.control.RockerSwitch
        PolyconvexityofsecondinvariantLabel  matlab.ui.control.Label
        QualityofFitPanel_iso           matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        UITable_qof_iso                 matlab.ui.control.Table
        OptimizeButton_iso              matlab.ui.control.Button
        PlotSelectionPanel_iso          matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        PlotButton_iso                  matlab.ui.control.Button
        GridLayout11                    matlab.ui.container.GridLayout
        tenthdataButton_iso             matlab.ui.control.StateButton
        fifthdataButton_iso             matlab.ui.control.StateButton
        ninethdataButton_iso            matlab.ui.control.StateButton
        fourthdataButton_iso            matlab.ui.control.StateButton
        eighthdataButton_iso            matlab.ui.control.StateButton
        thirddataButton_iso             matlab.ui.control.StateButton
        seventhdataButton_iso           matlab.ui.control.StateButton
        seconddataButton_iso            matlab.ui.control.StateButton
        sixthdataButton_iso             matlab.ui.control.StateButton
        firstdataButton_iso             matlab.ui.control.StateButton
        Image_iso                       matlab.ui.control.Image
        SystemParametersPanel_iso       matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        NumberofFreeEnergyFunctionsSpinner_iso  matlab.ui.control.Spinner
        NumberoffreeenergyfunctionsLabel  matlab.ui.control.Label
        DegreeofBsplinesSpinner_iso     matlab.ui.control.Spinner
        DegreeofBsplinesSpinner_2Label  matlab.ui.control.Label
        NumberofControlPointsSpinner_iso  matlab.ui.control.Spinner
        NumberofcontrolpointsLabel      matlab.ui.control.Label
        InputFilesPanel_iso             matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        SelectFileButton_iso_10         matlab.ui.control.Button
        EditField_iso_10                matlab.ui.control.EditField
        FileType_iso_10                 matlab.ui.control.DropDown
        SelectFileButton_iso_9          matlab.ui.control.Button
        EditField_iso_9                 matlab.ui.control.EditField
        FileType_iso_9                  matlab.ui.control.DropDown
        SelectFileButton_iso_8          matlab.ui.control.Button
        EditField_iso_8                 matlab.ui.control.EditField
        FileType_iso_8                  matlab.ui.control.DropDown
        SelectFileButton_iso_7          matlab.ui.control.Button
        EditField_iso_7                 matlab.ui.control.EditField
        FileType_iso_7                  matlab.ui.control.DropDown
        SelectFileButton_iso_6          matlab.ui.control.Button
        EditField_iso_6                 matlab.ui.control.EditField
        FileType_iso_6                  matlab.ui.control.DropDown
        SelectFileButton_iso_5          matlab.ui.control.Button
        EditField_iso_5                 matlab.ui.control.EditField
        FileType_iso_5                  matlab.ui.control.DropDown
        SelectFileButton_iso_4          matlab.ui.control.Button
        EditField_iso_4                 matlab.ui.control.EditField
        FileType_iso_4                  matlab.ui.control.DropDown
        SelectFileButton_iso_3          matlab.ui.control.Button
        EditField_iso_3                 matlab.ui.control.EditField
        FileType_iso_3                  matlab.ui.control.DropDown
        SelectFileButton_iso_2          matlab.ui.control.Button
        EditField_iso_2                 matlab.ui.control.EditField
        FileType_iso_2                  matlab.ui.control.DropDown
        SelectFileButton_iso_1          matlab.ui.control.Button
        EditField_iso_1                 matlab.ui.control.EditField
        FileType_iso_1                  matlab.ui.control.DropDown
        OthersTab                       matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        QualityofFitPanel_oth           matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        UITable_qof_oth                 matlab.ui.control.Table
        PlotSelectionPanel_oth          matlab.ui.container.Panel
        GridLayout8_3                   matlab.ui.container.GridLayout
        PlotButton_oth                  matlab.ui.control.Button
        GridLayout11_2                  matlab.ui.container.GridLayout
        tenthdataButton_oth             matlab.ui.control.StateButton
        fifthdataButton_oth             matlab.ui.control.StateButton
        ninethdataButton_oth            matlab.ui.control.StateButton
        fourthdataButton_oth            matlab.ui.control.StateButton
        eighthdataButton_oth            matlab.ui.control.StateButton
        thirddataButton_oth             matlab.ui.control.StateButton
        seventhdataButton_oth           matlab.ui.control.StateButton
        seconddataButton_oth            matlab.ui.control.StateButton
        sixthdataButton_oth             matlab.ui.control.StateButton
        firstdataButton_oth             matlab.ui.control.StateButton
        OptimizeButton_oth              matlab.ui.control.Button
        Image_oth                       matlab.ui.control.Image
        TensionOnlyPanel_2              matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        WeightoptimizationSwitch        matlab.ui.control.RockerSwitch
        WeightoptimizationSwitchLabel   matlab.ui.control.Label
        InputFilesPanel_oth             matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        SelectFileButton_oth_10         matlab.ui.control.Button
        EditField_oth_10                matlab.ui.control.EditField
        FileType_oth_10                 matlab.ui.control.DropDown
        SelectFileButton_oth_9          matlab.ui.control.Button
        EditField_oth_9                 matlab.ui.control.EditField
        FileType_oth_9                  matlab.ui.control.DropDown
        SelectFileButton_oth_8          matlab.ui.control.Button
        EditField_oth_8                 matlab.ui.control.EditField
        FileType_oth_8                  matlab.ui.control.DropDown
        SelectFileButton_oth_7          matlab.ui.control.Button
        EditField_oth_7                 matlab.ui.control.EditField
        FileType_oth_7                  matlab.ui.control.DropDown
        SelectFileButton_oth_6          matlab.ui.control.Button
        EditField_oth_6                 matlab.ui.control.EditField
        FileType_oth_6                  matlab.ui.control.DropDown
        SelectFileButton_oth_5          matlab.ui.control.Button
        EditField_oth_5                 matlab.ui.control.EditField
        FileType_oth_5                  matlab.ui.control.DropDown
        SelectFileButton_oth_4          matlab.ui.control.Button
        EditField_oth_4                 matlab.ui.control.EditField
        FileType_oth_4                  matlab.ui.control.DropDown
        SelectFileButton_oth_3          matlab.ui.control.Button
        EditField_oth_3                 matlab.ui.control.EditField
        FileType_oth_3                  matlab.ui.control.DropDown
        SelectFileButton_oth_2          matlab.ui.control.Button
        EditField_oth_2                 matlab.ui.control.EditField
        FileType_oth_2                  matlab.ui.control.DropDown
        SelectFileButton_oth_1          matlab.ui.control.Button
        EditField_oth_1                 matlab.ui.control.EditField
        FileType_oth_1                  matlab.ui.control.DropDown
        SelectmodelPanel                matlab.ui.container.Panel
        GridLayout12_2                  matlab.ui.container.GridLayout
        ModelListbox                    matlab.ui.control.ListBox
        Image2                          matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        DataTrainingSoftwareforAnisotropicHyperelasticMaterialsLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        Switch                          matlab.ui.control.Switch
        Image_CC                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        PlotofthefitPanel               matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        IdentifiedparametersPanel       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        UITable_out                     matlab.ui.control.Table
        InputdataPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

 
    methods (Access = private)
        
        function arrange_plot_buttons(app)
            global no_exp
            type = app.TypeofexperimentDropDown.Value;
            if type == "TS"
                app.seconddataButton.Enable = 1;
                app.thirddataButton.Enable = 1;
                app.fourthdataButton.Enable = 1;
                app.fifthdataButton.Enable = 1;
                app.sixthdataButton.Enable = 1;
            elseif type == "BT"
                if no_exp >= 2
                    app.seconddataButton.Enable = 1;
                else
                    app.seconddataButton.Enable = 0;
                end
                if no_exp >= 3
                    app.thirddataButton.Enable = 1;
                else
                    app.thirddataButton.Enable = 0;
                end
                if no_exp >= 4
                    app.fourthdataButton.Enable = 1;
                else
                    app.fourthdataButton.Enable = 0;
                end
                if no_exp >= 5
                    app.fifthdataButton.Enable = 1;
                else
                    app.fifthdataButton.Enable = 0;
                end
                if no_exp == 6
                    app.sixthdataButton.Enable = 1;
                else
                    app.sixthdataButton.Enable = 0;
                end
            else
                app.seconddataButton.Enable = 1;
                app.thirddataButton.Enable = 0;
                app.fourthdataButton.Enable = 0;
                app.fifthdataButton.Enable = 0;
                app.sixthdataButton.Enable = 0;
                app.thirddataButton.Value = 0;
                app.fourthdataButton.Value = 0;
                app.fifthdataButton.Value = 0;
                app.sixthdataButton.Value = 0;
            end
        end
        
        
        function out_control_sel(app)
            value = app.NumberoffreeenergyfunctionsSpinner.Value;
            if value == 2
                app.UITable_out.ColumnName = {'psi_1', 'psi_4.1'};
            elseif value == 3
                app.UITable_out.ColumnName = {'psi_1', 'psi_4.1','psi_4.2'};
            elseif value == 4
                app.UITable_out.ColumnName = {'psi_1', 'psi_4.1','psi_4.2', 'psi_4.3'};
            end
            if app.TypeofexperimentDropDown.Value == "TS"
                app.UITable_out.ColumnName = {'psi_1', 'psi_4f','psi_4s', 'psi_8fs'};
            end
        end
        
        
    function bt_plot(app,lam_1,lam_2,P_exp1,P_exp2,lam_1_ext,lam_2_ext,P_11,P_22,type1,type2,col1,col2,i)
            if isequal(lam_2,[])
                plot(app.UIAxes,lam_1,P_exp1,type1, 'Color', col1,'LineWidth', 1.5,'DisplayName',strcat('Data',32,int2str(i),32,'$P_{11}$'))
                plot(app.UIAxes,lam_1_ext,P_11, 'Color', col1, 'LineWidth', 1.5,'HandleVisibility','off')

            else
                if app.Switch.Value == "Stretch 1"
                plot(app.UIAxes,lam_1,P_exp1,type1, 'Color', col1,'LineWidth', 1.5,'DisplayName',strcat('Data',32,int2str(i),32,'$P_{11}$'))
                plot(app.UIAxes,lam_1_ext,P_11, 'Color', col1, 'LineWidth', 1.5,'HandleVisibility','off')
                plot(app.UIAxes,lam_1,P_exp2,type2, 'Color', col2,'LineWidth', 1.5,'DisplayName',strcat('Data',32,int2str(i),32,'$P_{22}$'))
                plot(app.UIAxes,lam_1_ext,P_22, 'Color', col2, 'LineWidth', 1.5,'HandleVisibility','off')
                else
                plot(app.UIAxes,lam_2,P_exp1,type1, 'Color', col1,'LineWidth', 1.5,'DisplayName',strcat('Data',32,int2str(i),32,'$P_{11}$'))
                plot(app.UIAxes,lam_2_ext,P_11, 'Color', col1, 'LineWidth', 1.5,'HandleVisibility','off')
                plot(app.UIAxes,lam_2,P_exp2,type2, 'Color', col2,'LineWidth', 1.5,'DisplayName',strcat('Data',32,int2str(i),32,'$P_{22}$'))
                plot(app.UIAxes,lam_2_ext,P_22, 'Color', col2, 'LineWidth', 1.5,'HandleVisibility','off')
                end
            end
            
        end
        
        
        function arrange_plot_buttons_iso(app)
            global txtfield_iso
            pltbut_iso = [app.firstdataButton_iso app.seconddataButton_iso app.thirddataButton_iso...
                 app.fourthdataButton_iso app.fifthdataButton_iso app.sixthdataButton_iso...
                  app.seventhdataButton_iso app.eighthdataButton_iso app.ninethdataButton_iso...
                   app.tenthdataButton_iso];
            for i = 1:length(txtfield_iso)
                txtval_iso(i) = string(txtfield_iso(i).Value);
            end
            
            plot_on = find(txtval_iso ~= "");
            plot_off = find(txtval_iso == "");
            for i = plot_on
                pltbut_iso(i).Enable = 1;
            end
            for i = plot_off
                pltbut_iso(i).Enable = 0;
            end

        end

        function arrange_plot_buttons_oth(app)
            global txtfield_oth
            pltbut_oth = [app.firstdataButton_oth app.seconddataButton_oth app.thirddataButton_oth...
                 app.fourthdataButton_oth app.fifthdataButton_oth app.sixthdataButton_oth...
                  app.seventhdataButton_oth app.eighthdataButton_oth app.ninethdataButton_oth...
                   app.tenthdataButton_oth];
            for i = 1:length(txtfield_oth)
                txtval_oth(i) = string(txtfield_oth(i).Value);
            end
            
            plot_on = find(txtval_oth ~= "");
            plot_off = find(txtval_oth == "");
            for i = plot_on
                pltbut_oth(i).Enable = 1;
            end
            for i = plot_off
                pltbut_oth(i).Enable = 0;
            end

        end
        
        function update_screen(app)
            screen_size = get(0,'screensize');

            if screen_size(3)<1280
                screen_size(1:2) = 0.05*screen_size(3:4);
                screen_size(3) = 734;
                screen_size(4) = 0.9*screen_size(4);
            else
                screen_size(1:2) = 0.05*screen_size(3:4);
                screen_size(3:4) = 0.9*screen_size(3:4);
            end
            app.HyperDataUIFigure.Position = screen_size;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
%======================================================================%
%           Computational Micromechanics Laboratory (CMML)             %
%              Middle East Technical University (METU)                 %
%----------------------------------------------------------------------%
%                        Hyper-Data Toolbox - v1.0                     %
%----------------------------------------------------------------------%
%   Authors: Recep Durna, Alp Kağan Açan, Oğuz Ziya Tikenoğulları      %
%   Software License: GNU GPL v3.0   Copyright (C) 2023 Hüsnü Dal      %
%======================================================================%
            warning('off','all');
%            filepaths

            update_screen(app)
            global no_exp no_data no_data_old selectfile_iso txtfield_iso typefield_iso
            global selectfile_oth txtfield_oth typefield_oth
            
            no_exp = 0;
            no_data = [];
            no_data_old = [];
            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P','Interpreter','Latex')
            selectfile_iso = [app.SelectFileButton_iso_1 app.SelectFileButton_iso_2 app.SelectFileButton_iso_3...
                 app.SelectFileButton_iso_4 app.SelectFileButton_iso_5 app.SelectFileButton_iso_6...
                  app.SelectFileButton_iso_7 app.SelectFileButton_iso_8 app.SelectFileButton_iso_9...
                   app.SelectFileButton_iso_10];
            txtfield_iso = [app.EditField_iso_1 app.EditField_iso_2 app.EditField_iso_3... 
                app.EditField_iso_4 app.EditField_iso_5 app.EditField_iso_6...
                app.EditField_iso_7 app.EditField_iso_8 app.EditField_iso_9...
                app.EditField_iso_10];
            typefield_iso = [app.FileType_iso_1 app.FileType_iso_2 app.FileType_iso_3...
                app.FileType_iso_4 app.FileType_iso_5 app.FileType_iso_6...
                 app.FileType_iso_7 app.FileType_iso_8 app.FileType_iso_9...
                  app.FileType_iso_10];

            selectfile_oth = [app.SelectFileButton_oth_1 app.SelectFileButton_oth_2 app.SelectFileButton_oth_3...
                 app.SelectFileButton_oth_4 app.SelectFileButton_oth_5 app.SelectFileButton_oth_6...
                  app.SelectFileButton_oth_7 app.SelectFileButton_oth_8 app.SelectFileButton_oth_9...
                   app.SelectFileButton_oth_10];
            txtfield_oth = [app.EditField_oth_1 app.EditField_oth_2 app.EditField_oth_3... 
                app.EditField_oth_4 app.EditField_oth_5 app.EditField_oth_6...
                app.EditField_oth_7 app.EditField_oth_8 app.EditField_oth_9...
                app.EditField_oth_10];
            typefield_oth = [app.FileType_oth_1 app.FileType_oth_2 app.FileType_oth_3...
                app.FileType_oth_4 app.FileType_oth_5 app.FileType_oth_6...
                 app.FileType_oth_7 app.FileType_oth_8 app.FileType_oth_9...
                  app.FileType_oth_10];
        end

        % Value changed function: TypeofexperimentDropDown
        function typeexp(app, event)
            global table_data no_exp
            no_exp = 0;
            value = app.TypeofexperimentDropDown.Value;
            app.Switch.Visible = 0;
            app.NumberoffreeenergyfunctionsSpinner.Enable = 1;
            app.ioButton.Enable = 1;
            app.TensiononlyconditionSwitch.Enable = 1;
            app.TensiononlyconditionSwitchLabel.Enable = 1;
            if value == "TS"
                app.fsLabel.Visible = 1;
                app.sfLabel.Visible = 1;
                app.fnLabel.Visible = 1;
                app.nfLabel.Visible = 1;
                app.snLabel.Visible = 1;
                app.nsLabel.Visible = 1;
                app.EditField_2.Enable = 1;
                app.SelectfileButton_2.Enable = 1;
                app.EditField_3.Enable = 1;
                app.SelectfileButton_3.Enable = 1;
                app.EditField_4.Enable = 1;
                app.SelectfileButton_4.Enable = 1;
                app.EditField_5.Enable = 1;
                app.SelectfileButton_5.Enable = 1;
                app.EditField_6.Enable = 1;
                app.SelectfileButton_6.Enable = 1;
                app.UITable.ColumnName = {'Shear','Pfs','Shear','Psf','Shear','Pfn','Shear','Pnf','Shear','Psn','Shear','Pns'}';
                app.NumberoffreeenergyfunctionsSpinner.Value = 4;
                app.NumberoffreeenergyfunctionsSpinner.Enable = 0;
                app.fsButton.Value = 1;
                app.ioButton.Enable = 0;
                app.TensiononlyconditionSwitch.Value = "On";
                app.TensiononlyconditionSwitch.Enable = 0;
                app.TensiononlyconditionSwitchLabel.Enable = 0;
            elseif value == "BT"
                app.Switch.Visible = 1;
                app.fsLabel.Visible = 0;
                app.sfLabel.Visible = 0;
                app.fnLabel.Visible = 0;
                app.nfLabel.Visible = 0;
                app.snLabel.Visible = 0;
                app.nsLabel.Visible = 0;
                app.EditField_2.Enable = 0;
                app.SelectfileButton_2.Enable = 0;
                app.EditField_3.Enable = 0;
                app.SelectfileButton_3.Enable = 0;
                app.EditField_4.Enable = 0;
                app.SelectfileButton_4.Enable = 0;
                app.EditField_5.Enable = 0;
                app.SelectfileButton_5.Enable = 0;
                app.EditField_6.Enable = 0;
                app.SelectfileButton_6.Enable = 0;
                app.UITable.ColumnName = {'Stretch1','Stretch2','P1','P2'}';
            else
                app.fsLabel.Visible = 0;
                app.sfLabel.Visible = 0;
                app.fnLabel.Visible = 0;
                app.nfLabel.Visible = 0;
                app.snLabel.Visible = 0;
                app.nsLabel.Visible = 0;
                app.EditField_2.Enable = 0;
                app.SelectfileButton_2.Enable = 0;
                app.EditField_3.Enable = 0;
                app.SelectfileButton_3.Enable = 0;
                app.EditField_4.Enable = 0;
                app.SelectfileButton_4.Enable = 0;
                app.EditField_5.Enable = 0;
                app.SelectfileButton_5.Enable = 0;
                app.EditField_6.Enable = 0;
                app.SelectfileButton_6.Enable = 0;
            end
            if value == "ET"
                app.EditField_2.Enable = 0;
                app.SelectfileButton_2.Enable = 0;
                app.UITable.ColumnName = {'Stretch','P1','P2'}';
            elseif value == "UT"
                app.EditField_2.Enable = 1;
                app.SelectfileButton_2.Enable = 1;
                app.UITable.ColumnName = {'Stretch1','P1','Stretch2','P2'}';
            end
            app.EditField.Value = '';
            app.EditField_2.Value = '';
            app.EditField_3.Value = '';
            app.EditField_4.Value = '';
            app.EditField_5.Value = '';
            app.EditField_6.Value = '';
            table_data = [];
            app.UITable.Data = table_data;
            n_free_select(app,event);
            arrange_plot_buttons(app);
            out_control_sel(app);

        end

        % Button pushed function: SelectfileButton
        function get_file1(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField.Value = '';
                return;
            end
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;

            if type == "ET"
                table_data(1:length(mem),1:3) = mem;
                app.UITable.Data = table_data(:,1:3);
            elseif type == "UT"
                table_data(1:length(mem),1:2) = mem;
                mem2 = size(table_data);
                if mem2(2)<4
                    table_data(1:length(mem),3:4) = 0;
                end
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                table_data(1:length(mem),1:2) = mem;
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                table_data(1:length(mem),1:4) = mem;
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end

            app.EditField.Value = strcat(path,file);

            value = app.EditField.Value;
            if app.TypeofexperimentDropDown.Value == "BT" & value ~= ""
                app.EditField_2.Enable = 1;
                app.SelectfileButton_2.Enable = 1;
                app.UITable.ColumnName = {'Stretch1','Stretch2','P1','P2'}';
                no_exp = max(no_exp,1);
            end
            arrange_plot_buttons(app);
        end

        % Button pushed function: SelectfileButton_2
        function get_file2(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField_2.Value = '';
                return;
            end
            
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;
            
            if type == "BT"
                table_data(1:length(mem),5:8) = mem;
            else
                table_data(1:length(mem),3:4) = mem;
            end
            type = app.TypeofexperimentDropDown.Value;
            if type == "UT"
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end

            app.EditField_2.Value = strcat(path,file);

            value = app.EditField_2.Value;
            if app.TypeofexperimentDropDown.Value == "BT" & value ~= ""
                app.EditField_3.Enable = 1;
                app.SelectfileButton_3.Enable = 1;
                if length(app.UITable.ColumnName) < 8
                    app.UITable.ColumnName(end+1:end+4) = app.UITable.ColumnName(1:4);
                end
                no_exp = max(no_exp,2);
            end
            arrange_plot_buttons(app);
        end

        % Button pushed function: SelectfileButton_3
        function get_file3(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField_3.Value = '';
                return;
            end
            
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;

            if type == "BT"
                table_data(1:length(mem),9:12) = mem;
            else
                table_data(1:length(mem),5:6) = mem;
            end
            type = app.TypeofexperimentDropDown.Value;
            if type == "ET"
                app.UITable.Data = table_data(:,1:2);
            elseif type == "UT"
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end
            
            app.EditField_3.Value = strcat(path,file);

            value = app.EditField_3.Value;
            if app.TypeofexperimentDropDown.Value == "BT" && value ~= ""
                app.EditField_4.Enable = 1;
                app.SelectfileButton_4.Enable = 1;
                if length(app.UITable.ColumnName) < 12
                    app.UITable.ColumnName(end+1:end+4) = app.UITable.ColumnName(1:4);
                end
                no_exp = max(no_exp,3);
            end
            arrange_plot_buttons(app);
        end

        % Button pushed function: SelectfileButton_4
        function get_file4(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField_4.Value = '';
                return;
            end
            
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;

            if type == "BT"
                table_data(1:length(mem),13:16) = mem;
            else
                table_data(1:length(mem),7:8) = mem;
            end
            type = app.TypeofexperimentDropDown.Value;
            if type == "ET"
                app.UITable.Data = table_data(:,1:2);
            elseif type == "UT"
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end

            app.EditField_4.Value = strcat(path,file);

            value = app.EditField_4.Value;
            if app.TypeofexperimentDropDown.Value == "BT" & value ~= ""
                app.EditField_5.Enable = 1;
                app.SelectfileButton_5.Enable = 1;
                if length(app.UITable.ColumnName) < 16
                    app.UITable.ColumnName(end+1:end+4) = app.UITable.ColumnName(1:4);
                end
                no_exp = max(no_exp,4);
            end
            arrange_plot_buttons(app);
        end

        % Button pushed function: SelectfileButton_5
        function get_file5(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField_5.Value = '';
                return;
            end
            
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;

            if type == "BT"
                table_data(1:length(mem),17:20) = mem;
            else
                table_data(1:length(mem),9:10) = mem;
            end
            type = app.TypeofexperimentDropDown.Value;
            if type == "ET"
                app.UITable.Data = table_data(:,1:2);
            elseif type == "UT"
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end

            app.EditField_5.Value = strcat(path,file);
            
            value = app.EditField_5.Value;
            if app.TypeofexperimentDropDown.Value == "BT" & value ~= ""
                app.EditField_6.Enable = 1;
                app.SelectfileButton_6.Enable = 1;
                if length(app.UITable.ColumnName) < 20
                    app.UITable.ColumnName(end+1:end+4) = app.UITable.ColumnName(1:4);
                end
                no_exp = max(no_exp,5);
            end
            arrange_plot_buttons(app);
        end

        % Button pushed function: SelectfileButton_6
        function get_file6(app, event)
            global table_data no_exp
            [file,path] = uigetfile({'*.dat';'*.txt'});

            figure(app.HyperDataUIFigure);

            if file == 0
                app.EditField_6.Value = '';
                return;
            end
            
            mem = dlmread(strcat(path,file));
            type = app.TypeofexperimentDropDown.Value;

            if type == "BT"
                table_data(1:length(mem),21:24) = mem;
            else
                table_data(1:length(mem),11:12) = mem;
            end
            type = app.TypeofexperimentDropDown.Value;
            if type == "ET"
                app.UITable.Data = table_data(:,1:2);
            elseif type == "UT"
                app.UITable.Data = table_data(:,1:4);
            elseif type == "TS"
                mem2 = size(table_data);
                table_data(:,min(mem2(2),12)+1:12) = 0;
                app.UITable.Data = table_data(:,1:12);
            elseif type == "BT"
                mem2 = size(table_data);
                table_data(:,mem2(2)+1:no_exp*4+4) = 0;
                app.UITable.Data = table_data(:,1:no_exp*4+4);
            end

            app.EditField_6.Value = strcat(path,file);
            
            value = app.EditField_5.Value;
            if app.TypeofexperimentDropDown.Value == "BT" && value ~= ""
                app.EditField_6.Enable = 1;
                app.SelectfileButton_6.Enable = 1;
                if length(app.UITable.ColumnName) < 24
                    app.UITable.ColumnName(end+1:end+4) = app.UITable.ColumnName(1:4);
                end
                no_exp = max(no_exp,6);
            end
            arrange_plot_buttons(app);
        end

        % Value changed function: NumberoffreeenergyfunctionsSpinner
        function n_free_select(app, event)
            disp_mod_sel(app, event);
            out_control_sel(app);
        end

        % Selection changed function: DispersionModelButtonGroup
        function disp_mod_sel(app, event)
            selectedButton = app.DispersionModelButtonGroup.SelectedObject;
            if app.TypeofexperimentDropDown.Value == "TS"
                value = 3;
            else
                value = app.NumberoffreeenergyfunctionsSpinner.Value;
            end
            
            app.DirectionofFiber1EditField_3.Enable = 1;
            if value == 2
                app.Directionoffiber2EditFieldLabel.Enable = 0;
                app.Directionoffiber2EditField.Enable = 0;
                app.DirectionofFiber2EditField_2.Enable = 0;
                app.DirectionofFiber2EditField_3.Enable = 0;
                app.Directionoffiber3EditFieldLabel.Enable = 0;
                app.Directionoffiber3EditField.Enable = 0;
                app.DirectionofFiber3EditField_2.Enable = 0;
                app.DirectionofFiber3EditField_3.Enable = 0;
            elseif value == 3
                app.Directionoffiber2EditFieldLabel.Enable = 1;
                app.Directionoffiber2EditField.Enable = 1;
                app.DirectionofFiber2EditField_2.Enable = 1;
                app.DirectionofFiber2EditField_3.Enable = 1;
                app.Directionoffiber3EditFieldLabel.Enable = 0;
                app.Directionoffiber3EditField.Enable = 0;
                app.DirectionofFiber3EditField_2.Enable = 0;
                app.DirectionofFiber3EditField_3.Enable = 0;
            elseif value == 4
                app.Directionoffiber2EditFieldLabel.Enable = 1;
                app.Directionoffiber2EditField.Enable = 1;
                app.DirectionofFiber2EditField_2.Enable = 1;
                app.DirectionofFiber2EditField_3.Enable = 1;
                app.Directionoffiber3EditFieldLabel.Enable = 1;
                app.Directionoffiber3EditField.Enable = 1;
                app.DirectionofFiber3EditField_2.Enable = 1;
                app.DirectionofFiber3EditField_3.Enable = 1;
            end

            if selectedButton.Text == "fs"
                app.kappa_ipEditField.Enable = 0;
                app.kappa_ipLabel.Enable = 0;
                app.kappa_opEditField.Enable = 0;
                app.kappa_opLabel.Enable = 0;
                app.kappa_1EditField.Enable = 1;
                app.kappa_1Label.Enable = 1;
                if value == 2
                    app.kappa_2EditField.Enable = 0;
                    app.kappa_3EditField.Enable = 0;
                    app.kappa_2Label.Enable = 0;
                    app.kappa_3Label.Enable = 0;
                elseif value == 3
                    app.kappa_2EditField.Enable = 1;
                    app.kappa_3EditField.Enable = 0;
                    app.kappa_2Label.Enable = 1;
                    app.kappa_3Label.Enable = 0;
                elseif value == 4
                    app.kappa_2EditField.Enable = 1;
                    app.kappa_3EditField.Enable = 1;
                    app.kappa_2Label.Enable = 1;
                    app.kappa_3Label.Enable = 1;
                end
            else
                app.kappa_ipEditField.Enable = 1;
                app.kappa_opEditField.Enable = 1;
                app.kappa_1EditField.Enable = 0;
                app.kappa_2EditField.Enable = 0;
                app.kappa_3EditField.Enable = 0;
                app.kappa_ipLabel.Enable = 1;
                app.kappa_opLabel.Enable = 1;
                app.kappa_1Label.Enable = 0;
                app.kappa_2Label.Enable = 0;
                app.kappa_3Label.Enable = 0;

                app.DirectionofFiber1EditField_3.Value = 0;
                app.DirectionofFiber2EditField_3.Value = 0;
                app.DirectionofFiber3EditField_3.Value = 0;
                app.DirectionofFiber1EditField_3.Enable = 0;
                app.DirectionofFiber2EditField_3.Enable = 0;
                app.DirectionofFiber3EditField_3.Enable = 0;
            end
            out_control_sel(app);
        end

        % Button pushed function: OptimizeButton
        function run(app, event)
            global dispmodel kip kop kappaf kappas fib kappafib n_free_ener no_exp tnsonlycond

            app.OptimizeButton.Enable = 0;

            drawnow;
            type = app.TypeofexperimentDropDown.Value;
            dataloc1 = app.EditField.Value;
            if type == "UT"
                dataloc2= app.EditField_2.Value;
            elseif type == "TS"
                datalocfs = app.EditField.Value;
                datalocsf = app.EditField_2.Value;
                datalocfn = app.EditField_3.Value;
                datalocnf = app.EditField_4.Value;
                datalocsn = app.EditField_5.Value;
                datalocns = app.EditField_6.Value;
            end

            if type == "BT"
                Loc_Data = {dataloc1};
                if no_exp >= 2
                    Loc_Data(end+1) = {app.EditField_2.Value};
                end
                if no_exp >= 3
                    Loc_Data(end+1) = {app.EditField_3.Value};
                end
                if no_exp >= 4
                    Loc_Data(end+1) = {app.EditField_4.Value};
                end
                if no_exp >= 5
                    Loc_Data(end+1) = {app.EditField_5.Value};
                end
                if no_exp == 6
                    Loc_Data(end+1) = {app.EditField_6.Value};
                end
            end

            n_ctrl_pts = app.NumberofcontrolpointsSpinner.Value;
            degree = app.DegreeofBsplinesSpinner.Value;
            n_free_ener = app.NumberoffreeenergyfunctionsSpinner.Value;
            if app.fsButton.Value
                dispmodel = 'fs';
            elseif app.ioButton.Value
                dispmodel = 'io';
            end

            clear app_fiber
            kappafib = [];
            fib = [];

            if app.kappa_ipEditField.Enable
                kip = app.kappa_ipEditField.Value;
                kop = app.kappa_opEditField.Value;
            end
            if app.kappa_1EditField.Enable
                kappaf = app.kappa_1EditField.Value;
                kappafib(1) = kappaf;
            end
            if app.kappa_2EditField.Enable
                kappas = app.kappa_2EditField.Value;
                kappafib(2) = kappas;
            end
            if app.kappa_3EditField.Enable
                kappafib(3) = app.kappa_3EditField.Value;
            end
            
            if app.Directionoffiber1EditField.Enable
                app_fiber(1,1) = app.Directionoffiber1EditField.Value;
                app_fiber(2,1) = app.DirectionofFiber1EditField_2.Value;
                app_fiber(3,1) = app.DirectionofFiber1EditField_3.Value;
            end
            if app.Directionoffiber2EditField.Enable
                app_fiber(1,2) = app.Directionoffiber2EditField.Value;
                app_fiber(2,2) = app.DirectionofFiber2EditField_2.Value;
                app_fiber(3,2) = app.DirectionofFiber2EditField_3.Value;
            end
            if app.Directionoffiber3EditField.Enable
                app_fiber(1,3) = app.Directionoffiber3EditField.Value;
                app_fiber(2,3) = app.DirectionofFiber3EditField_2.Value;
                app_fiber(3,2) = app.DirectionofFiber3EditField_3.Value;
            end
            
            if type == "TS"
                fib_up_lim = n_free_ener-2;
            else
                fib_up_lim = n_free_ener-1;
            end

            for i = 1:fib_up_lim
                fib(:,i) = app_fiber(:,i)/norm(app_fiber(:,i));
            end

            if app.TensiononlyconditionSwitch.Value == "On"
                tnsonlycond = 1;
            else
                tnsonlycond = 0;
            end

            global Sto_lam_et Sto_lam_ut1 Sto_lam_ut2 Sto_shear Sto_Pexp1 Sto_Pexp2 Sto_P11 Sto_P22
            global Sto_Pfs Sto_Psf Sto_Pfn Sto_Pnf Sto_Psn Sto_Pns Sto_Pexpfs Sto_Pexpsf Sto_Pexpfn Sto_Pexpnf Sto_Pexpsn Sto_Pexpns
            global Sto_lam_1 Sto_lam_2 bounds Sto_lam_1_ext Sto_lam_2_ext bounds_ext
            global Sto_lam_et_ext Sto_lam_ut1_ext Sto_lam_ut2_ext Sto_shear_ext
            main_gui

            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P','Interpreter','Latex')
            if type == "ET"

                plot(app.UIAxes,lam_et,P_et1,'o', 'Color', "#A2142F",'LineWidth', 1.5)
                plot(app.UIAxes,lam_et_ext,P_11, 'Color', "#0072BD", 'LineWidth', 1.5)
                
                plot(app.UIAxes,lam_et,P_et2,'o', 'Color', "#D95319",'LineWidth', 1.5)
                plot(app.UIAxes,lam_et_ext,P_22, 'Color', "#4DBEEE", 'LineWidth', 1.5)

                legend(app.UIAxes,'$P_{11}^{\mathrm{(exp)}}$','$P_{11}^{\mathrm{(fit)}}$',...
                    '$P_{22}^{\mathrm{(exp)}}$','$P_{22}^{\mathrm{(fit)}}$', 'Location', 'NorthWest','Interpreter','Latex')
                
                Sto_lam_et = lam_et;
                Sto_lam_et_ext = lam_et_ext;
                Sto_Pexp1 = P_et1;
                Sto_Pexp2 = P_et2;
                Sto_P11 = P_11;
                Sto_P22 = P_22;

                app.UITable_qof.Data = double([qof_tot; qof1; qof2]);
            elseif type == "UT"

                plot(app.UIAxes,lam_ut1,P_ut1,'o', 'Color', "#A2142F",'LineWidth', 1.5)
                plot(app.UIAxes,lam_ut1_ext,P11, 'Color', "#0072BD", 'LineWidth', 1.5)
                
                plot(app.UIAxes,lam_ut2,P_ut2,'o', 'Color', "#D95319",'LineWidth', 1.5)
                plot(app.UIAxes,lam_ut2_ext,P22, 'Color', "#4DBEEE", 'LineWidth', 1.5)
                
                legend(app.UIAxes,'$P_{11}^{\mathrm{(exp)}}$','$P_{11}^{\mathrm{(fit)}}$',...
                    '$P_{22}^{\mathrm{(exp)}}$','$P_{22}^{\mathrm{(fit)}}$', 'Location', 'NorthWest','Interpreter','Latex')

                Sto_lam_ut1 = lam_ut1;
                Sto_lam_ut2 = lam_ut2;
                Sto_lam_ut1_ext = lam_ut1_ext;
                Sto_lam_ut2_ext = lam_ut2_ext;
                Sto_Pexp1 = P_ut1;
                Sto_Pexp2 = P_ut2;
                Sto_P11 = P11;
                Sto_P22 = P22;

                app.UITable_qof.Data = double([qof_tot; qof1; qof2]);

            elseif type == "TS"

                plot(app.UIAxes,shear,Pexp_fs,'o', 'Color', "#0072BD", 'LineWidth', 1.5, 'DisplayName', 'fs mode')
                plot(app.UIAxes,shear_ext,P_fs, 'Color', "#0072BD",'LineWidth', 1.5, 'HandleVisibility','off')
                
                plot(app.UIAxes,shear,Pexp_sf,'*', 'Color', "#EDB120", 'LineWidth', 1.5, 'DisplayName', 'sf mode')
                plot(app.UIAxes,shear_ext,P_sf, 'Color', "#EDB120",'LineWidth', 1.5, 'HandleVisibility','off')
                
                plot(app.UIAxes,shear,Pexp_fn,'+', 'Color', "#A2142F", 'LineWidth', 1.5, 'DisplayName', 'fn mode')
                plot(app.UIAxes,shear_ext,P_fn, 'Color', "#A2142F",'LineWidth', 1.5, 'HandleVisibility','off')
                
                plot(app.UIAxes,shear,Pexp_nf,'square', 'Color', "#4DBEEE", 'LineWidth', 1.5, 'DisplayName', 'nf mode')
                plot(app.UIAxes,shear_ext,P_nf, 'Color', "#4DBEEE",'LineWidth', 1.5, 'HandleVisibility','off')
                
                plot(app.UIAxes,shear,Pexp_sn,'x', 'Color', "#D95319", 'LineWidth', 1.5, 'DisplayName', 'sn mode')
                plot(app.UIAxes,shear_ext,P_sn, 'Color', "#D95319",'LineWidth', 1.5, 'HandleVisibility','off')
                
                plot(app.UIAxes,shear,Pexp_ns,'diamond', 'Color', "#77AC30", 'LineWidth', 1.5, 'DisplayName', 'ns mode')
                plot(app.UIAxes,shear_ext,P_ns, '--', 'Color', "#77AC30",'LineWidth', 1.5, 'HandleVisibility','off')

                plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
                
                legend(app.UIAxes,'Location', 'NorthWest')
                xlabel(app.UIAxes,'$\gamma$')

                Sto_shear = shear;
                Sto_shear_ext = shear_ext;
                Sto_Pexpfs = Pexp_fs;
                Sto_Pexpsf = Pexp_sf;
                Sto_Pexpfn = Pexp_fn;
                Sto_Pexpnf = Pexp_nf;
                Sto_Pexpsn = Pexp_sn;
                Sto_Pexpns = Pexp_ns;
                Sto_Pfs = P_fs;
                Sto_Psf = P_sf;
                Sto_Pfn = P_fn;
                Sto_Pnf = P_nf;
                Sto_Psn = P_sn;
                Sto_Pns = P_ns;

                app.UITable_qof.Data = double([qof_tot; qof_fs; qof_sf; qof_fn; qof_nf; qof_sn; qof_ns]);
            elseif type == "BT"
                type1 = ["o","pentagram","+","square","^",">"];
                type2 = ["*","hexagram","x","diamond","v","<"];
                col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30"];
                col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30"];
                for i = 1:bnds_sz(1)
                    
                    bt_plot(app,lam_1(bounds(i,1):bounds(i,2)),lam_2(bounds(i,1):bounds(i,2)), ...
                        P_exp1(bounds(i,1):bounds(i,2)),P_exp2(bounds(i,1):bounds(i,2)), ...
                        lam_1_ext(bounds_ext(i,1):bounds_ext(i,2)),lam_2_ext(bounds_ext(i,1):bounds_ext(i,2)), ...
                        P_11(bounds_ext(i,1):bounds_ext(i,2)),P_22(bounds_ext(i,1):bounds_ext(i,2)), ...
                        type1(i),type2(i),col1(i),col2(i),i)
                end

                plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
                
                legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
                qof_Table = qof_tot;
                for i = 1:bnds_sz(1)
                    qof_Table(end+1,:) = qof_tot_ind(i,:);
                end
                qof_Table = double(qof_Table);
                app.UITable_qof.Data = double(qof_Table);

                Sto_lam_1 = lam_1;
                Sto_lam_2 = lam_2;
                Sto_lam_1_ext = lam_1_ext;
                Sto_lam_2_ext = lam_2_ext;
                Sto_Pexp1 = P_exp1;
                Sto_Pexp2 = P_exp2;
                Sto_P11 = P_11;
                Sto_P22 = P_22;
            end
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

            
            app.UITable_out.Data = opt_control_pts;
            app.PlotselectionPanel.Enable = "on";

            app.OptimizeButton.Enable = 1;
        end

        % Button pushed function: PlotButton
        function plot_button(app, event)

            global Sto_lam_et Sto_lam_ut1 Sto_lam_ut2 Sto_shear Sto_Pexp1 Sto_Pexp2 Sto_P11 Sto_P22
            global Sto_Pfs Sto_Psf Sto_Pfn Sto_Pnf Sto_Psn Sto_Pns Sto_Pexpfs Sto_Pexpsf Sto_Pexpfn Sto_Pexpnf Sto_Pexpsn Sto_Pexpns
            global Sto_lam_1 Sto_lam_2 bounds Sto_lam_1_ext Sto_lam_2_ext bounds_ext
            global Sto_lam_et_ext Sto_lam_ut1_ext Sto_lam_ut2_ext Sto_shear_ext

            type = app.TypeofexperimentDropDown.Value;
            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')

            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P')
            if type == "ET"
                if app.firstdataButton.Value
                    plot(app.UIAxes,Sto_lam_et,Sto_Pexp1,'o', 'Color', "#A2142F",'LineWidth', 1.5,'DisplayName','$P_{11}^{\mathrm{(exp)}}$')
                    plot(app.UIAxes,Sto_lam_et_ext,Sto_P11, 'Color', "#0072BD", 'LineWidth', 1.5, 'DisplayName', '$P_{11}^{\mathrm{(fit)}}$')
                end
                if app.seconddataButton.Value
                    plot(app.UIAxes,Sto_lam_et,Sto_Pexp2,'o', 'Color', "#D95319",'LineWidth', 1.5, 'DisplayName','$P_{22}^{\mathrm{(exp)}}$')
                    plot(app.UIAxes,Sto_lam_et_ext,Sto_P22, 'Color', "#4DBEEE", 'LineWidth', 1.5, 'DisplayName','$P_{22}^{\mathrm{(fit)}}$')
                end
            elseif type == "UT"
                if app.firstdataButton.Value
                    plot(app.UIAxes,Sto_lam_ut1,Sto_Pexp1,'o', 'Color', "#A2142F",'LineWidth', 1.5,'DisplayName','$P_{11}^{\mathrm{(exp)}}$')
                    plot(app.UIAxes,Sto_lam_ut1_ext,Sto_P11, 'Color', "#0072BD", 'LineWidth', 1.5,'DisplayName','$P_{11}^{\mathrm{(fit)}}$')
                end
                if app.seconddataButton.Value
                    plot(app.UIAxes,Sto_lam_ut2,Sto_Pexp2,'o', 'Color', "#D95319",'LineWidth', 1.5, 'DisplayName','$P_{22}^{\mathrm{(exp)}}$')
                    plot(app.UIAxes,Sto_lam_ut2_ext,Sto_P22, 'Color', "#4DBEEE", 'LineWidth', 1.5, 'DisplayName', '$P_{22}^{\mathrm{(fit)}}$')
                end
            elseif type == "TS"
                
                if app.firstdataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpfs,'o', 'Color', "#0072BD", 'LineWidth', 1.5, 'DisplayName', 'fs mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Pfs, 'Color', "#0072BD",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.seconddataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpsf,'*', 'Color', "#EDB120", 'LineWidth', 1.5, 'DisplayName', 'sf mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Psf, 'Color', "#EDB120",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.thirddataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpfn,'+', 'Color', "#A2142F", 'LineWidth', 1.5, 'DisplayName', 'fn mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Pfn, 'Color', "#A2142F",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.fourthdataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpnf,'square', 'Color', "#4DBEEE", 'LineWidth', 1.5, 'DisplayName', 'nf mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Pnf, 'Color', "#4DBEEE",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.fifthdataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpsn,'x', 'Color', "#D95319", 'LineWidth', 1.5, 'DisplayName', 'sn mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Psn, 'Color', "#D95319",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.sixthdataButton.Value
                    plot(app.UIAxes,Sto_shear,Sto_Pexpns,'diamond', 'Color', "#77AC30", 'LineWidth', 1.5, 'DisplayName', 'ns mode')
                    plot(app.UIAxes,Sto_shear_ext,Sto_Pns, '--', 'Color', "#77AC30",'LineWidth', 1.5, 'HandleVisibility','off')
                end
                if app.firstdataButton.Value || app.seconddataButton.Value || app.thirddataButton.Value || app.fourthdataButton.Value || app.fifthdataButton.Value || app.sixthdataButton.Value
                    plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
                end
                xlabel(app.UIAxes,'$\gamma$')
            elseif type == "BT"
                if app.firstdataButton.Value
                    plt_slc(1) = 1;
                else
                    plt_slc(1) = 0;
                end
                if app.seconddataButton.Value
                    plt_slc(2) = 2;
                else
                    plt_slc(2) = 0;
                end
                if app.thirddataButton.Value
                    plt_slc(3) = 3;
                else
                    plt_slc(3) = 0;
                end
                if app.fourthdataButton.Value
                    plt_slc(4) = 4;
                else
                    plt_slc(4) = 0;
                end
                if app.fifthdataButton.Value
                    plt_slc(5) = 5;
                else
                    plt_slc(5) = 0;
                end
                if app.sixthdataButton.Value
                    plt_slc(6) = 6;
                else
                    plt_slc(6) = 0;
                end
                type1 = ["o","pentagram","+","square","^",">"];
                type2 = ["*","hexagram","x","diamond","v","<"];
                col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30"];
                col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30"];
                for i = plt_slc
                    if i ~= 0
                        bt_plot(app,Sto_lam_1(bounds(i,1):bounds(i,2)),Sto_lam_2(bounds(i,1):bounds(i,2)), ...
                            Sto_Pexp1(bounds(i,1):bounds(i,2)),Sto_Pexp2(bounds(i,1):bounds(i,2)), ...
                            Sto_lam_1_ext(bounds_ext(i,1):bounds_ext(i,2)),Sto_lam_2_ext(bounds_ext(i,1):bounds_ext(i,2)), ...
                            Sto_P11(bounds_ext(i,1):bounds_ext(i,2)),Sto_P22(bounds_ext(i,1):bounds_ext(i,2)), ...
                            type1(i),type2(i),col1(i),col2(i),i)
                    end
                end

                plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
                
            end

            legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

        end

        % Image clicked function: Image2
        function reset_bt(app, event)
            % Make current instance of app invisible
            app.HyperDataUIFigure.Visible = 'off';
            % Open 2nd instance of app
            hyperdata();  % <--------------The name of your app
            % Delete old instance
            close(app.HyperDataUIFigure)
        end

        % Image clicked function: Image_2, Image_iso, Image_oth
        function reset_optimize(app, event)
            %
            app.OptimizeButton.Enable = 1;
            app.OptimizeButton_iso.Enable = 1;
            app.OptimizeButton_oth.Enable = 1;
            %
        end

        % Selection change function: TabGroup
        function isotropyselect(app, event)
            global no_exp table_data no_data
            no_exp = 0;
            selectedTxt = app.TabGroup.SelectedTab.Title;
            if selectedTxt == "Anisotropic"
                typeexp(app, event)
                out_control_sel(app)
            elseif selectedTxt == "Isotropic"
                app.UITable_out.ColumnName = {'psi_1'; 'psi_2'};
            end
            table_data = [];
            no_data = [];
        end

        % Button pushed function: SelectFileButton_iso_1, 
        % ...and 9 other components
        function getfile_iso(app, event)
            global table_data no_data no_data_old txtfield_iso selectfile_iso typefield_iso
            [file,path] = uigetfile({'*.dat';'*.txt'});

            for i = 1:length(txtfield_iso)
                tag_iso(i) = string(txtfield_iso(i).Tag);
            end

            filenum = find(tag_iso == event.Source.Tag);

            figure(app.HyperDataUIFigure);
            
            if file == 0
                txtfield_iso(filenum).Value = '';
                return;
            end

            mem = dlmread(strcat(path,file));
            type = typefield_iso(filenum).Value;
            if filenum == 1
                no_data(filenum,1) = 1;
            else
                no_data(filenum,1) = no_data(filenum-1,2)+1;
            end
            if type == "ET"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "UT"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "PS"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "BT"
                no_data(filenum,2) = no_data(filenum,1)+3;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','Stretch2','P1','P2'}';
            end
            sz_dat = size(no_data);
            if isequal(size(no_data_old),sz_dat)
                if not(isequal(no_data, no_data_old))
                    for i = 1:sz_dat(1)
                        type1 = typefield_iso(i).Value;
                        if i == 1
                            no_data(i,1) = 1;
                        else
                            no_data(i,1) = no_data(i-1,2)+1;
                        end
                        if type1 == "ET"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "UT"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "PS"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "BT"
                            no_data(i,2) = no_data(i,1)+3;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','Stretch2','P1','P2'}';
                        end
                        if i ~= filenum
                            table_data(:,no_data(i,1):no_data(i,2)) = app.UITable.Data(:,no_data_old(i,1):no_data_old(i,2));
                        end
                    end
                end
            end
            no_data_old = no_data;
            table_data(1:length(mem),no_data(filenum,1):no_data(filenum,2)) = mem;
            app.UITable.Data = table_data;

            txtfield_iso(filenum).Value = strcat(path,file);

            value = txtfield_iso(filenum).Value;
            if value ~= "" & filenum ~= 10
                txtfield_iso(filenum+1).Enable = 1;
                selectfile_iso(filenum+1).Enable = 1;
                typefield_iso(filenum+1).Enable = 1;
            end
            arrange_plot_buttons_iso(app);
        end

        % Button pushed function: OptimizeButton_iso
        function run_iso(app, event)
            global txtfield_iso typefield_iso

            app.OptimizeButton_iso.Enable = 0;

            drawnow;

            form_type = app.FormulationTypeListBox.Value;

            app.Switch.Visible = 0;
            Data_Info = {};
            for i = 1:length(txtfield_iso)
                if txtfield_iso(i).Value ~= ""
                    Data_Info(i,:) = {typefield_iso(i).Value; txtfield_iso(i).Value};
                    if typefield_iso(i).Value == "BT" > 0
                        app.Switch.Visible = 1;
                        app.Switch.Enable = 1;
                    end
                end
            end

            n_ctrl_pts = app.NumberofControlPointsSpinner_iso.Value;
            degree = app.DegreeofBsplinesSpinner_iso.Value;
            n_free_ener = app.NumberofFreeEnergyFunctionsSpinner_iso.Value;
            poly_switch = app.PolyconvexitySwitch.Value;

            global Iso_lam_ut Iso_lam_et Iso_lam_ps Iso_lam1_be Iso_lam2_be
            global Iso_lam_ut_ext Iso_lam_et_ext Iso_lam_ps_ext Iso_lam1_be_ext Iso_lam2_be_ext
            global Iso_P_ut Iso_P_et Iso_P_ps Iso_P1_be Iso_P2_be data_pos
            global Iso_Pfit_ut Iso_Pfit_et Iso_Pfit_ps Iso_Pfit1_be Iso_Pfit2_be     
            main_gui_iso

            Iso_lam_ut = lam_ut;
            Iso_lam_et = lam_et;
            Iso_lam_ps = lam_ps;
            Iso_lam1_be = lam1_be;
            Iso_lam2_be = lam2_be;
            Iso_lam_ut_ext = lam_ut_ext;
            Iso_lam_et_ext = lam_et_ext;
            Iso_lam_ps_ext = lam_ps_ext;
            Iso_lam1_be_ext = lam1_be_ext;
            Iso_lam2_be_ext = lam2_be_ext;
            Iso_P_ut = P_ut;
            Iso_P_et = P_et;
            Iso_P_ps = P_ps;
            Iso_P1_be = P1_be;
            Iso_P2_be = P2_be;
            Iso_Pfit_ut = Pdata_ut_ext;
            Iso_Pfit_et = Pdata_et_ext;
            Iso_Pfit_ps = Pdata_ps_ext;
            Iso_Pfit1_be = Pdata1_be_ext;
            Iso_Pfit2_be = Pdata2_be_ext;

            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P','Interpreter','Latex')

            type1 = ["o","pentagram","+","square","^",">","hexagram","x","diamond","v","<"];
            type2 = ["*","hexagram","x","diamond","v","<","pentagram","+","square","^",">"];
            col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30","#7E2F8E","#EB9A5C","#0E9E2F","#EB5C9D"];
            col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30","#9E2F8E","#FB9A5C","#6E9E2F","#DB5C9D"];

            qof_tot = [];
            totsz = size(data_pos);
            for i = 1:totsz(1)
                switch string(data_pos(i,1))
                    case "UT"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_ut(bnds(1):bnds(2));
                        P_exp1 = P_ut(bnds(1):bnds(2));
                        P_11 = Pdata_ut(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);

                        bnds_ext = cell2mat(data_pos(i,3));
                        lam_1_ext = lam_ut_ext(bnds_ext(1):bnds_ext(2));
                        P_11 = Pdata_ut_ext(bnds_ext(1):bnds_ext(2));
                        lam_2_ext = [];
                    case "ET"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_et(bnds(1):bnds(2));
                        P_exp1 = P_et(bnds(1):bnds(2));
                        P_11 = Pdata_et(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);

                        bnds_ext = cell2mat(data_pos(i,3));
                        lam_1_ext = lam_et_ext(bnds_ext(1):bnds_ext(2));
                        P_11 = Pdata_et_ext(bnds_ext(1):bnds_ext(2));
                        lam_2_ext = [];
                    case "PS"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_ps(bnds(1):bnds(2));
                        P_exp1 = P_ps(bnds(1):bnds(2));
                        P_11 = Pdata_ps(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);

                        bnds_ext = cell2mat(data_pos(i,3));
                        lam_1_ext = lam_ps_ext(bnds_ext(1):bnds_ext(2));
                        P_11 = Pdata_ps_ext(bnds_ext(1):bnds_ext(2));
                        lam_2_ext = [];
                    case "BT"
                        bnds = cell2mat(data_pos(i,2));
                        lam_2 = lam2_be(bnds(1):bnds(2));
                        P_exp2 = P2_be(bnds(1):bnds(2));
                        P_22 = Pdata2_be(bnds(1):bnds(2));
                        lam_1 = lam1_be(bnds(1):bnds(2));
                        P_exp1 = P1_be(bnds(1):bnds(2));
                        P_11 = Pdata1_be(bnds(1):bnds(2));
                        qof1 = qof_function(lam_1, P_11, P_exp1);
                        qof2 = qof_function(lam_2, P_22, P_exp2);
                        qof_tot(end+1,:) = qof1+qof2;

                        bnds_ext = cell2mat(data_pos(i,3));
                        lam_2_ext = lam2_be_ext(bnds_ext(1):bnds_ext(2));
                        P_22 = Pdata2_be_ext(bnds_ext(1):bnds_ext(2));
                        lam_1_ext = lam1_be_ext(bnds_ext(1):bnds_ext(2));
                        P_11 = Pdata1_be_ext(bnds_ext(1):bnds_ext(2));
                end
                
                bt_plot(app,lam_1,lam_2,P_exp1,P_exp2,lam_1_ext,lam_2_ext,P_11,P_22,type1(i),type2(i),col1(i),col2(i),i)
    
            end
            
            plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
            
            legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
            
            sz_qof = size(qof_tot);
            if sz_qof(1) == 1
                qof_Table = qof_tot;
            else
                qof_Table = [sum(qof_tot); qof_tot];
            end

            app.UITable_qof_iso.Data = double(qof_Table);
        
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

            
            app.UITable_out.Data = opt_control_pts;
            app.PlotSelectionPanel_iso.Enable = "on";

            if form_type == "Principal stretch-based"
                app.UITable_out.ColumnName = {"beta"; "gamma"};
            else
                app.UITable_out.ColumnName = {"psi_1"; "psi_2"};
            end

            app.OptimizeButton_iso.Enable = 1;
        end

        % Button pushed function: PlotButton_iso
        function plot_button_iso(app, event)
            global Iso_lam_ut Iso_lam_et Iso_lam_ps Iso_lam1_be Iso_lam2_be
            global Iso_lam_ut_ext Iso_lam_et_ext Iso_lam_ps_ext Iso_lam1_be_ext Iso_lam2_be_ext
            global Iso_P_ut Iso_P_et Iso_P_ps Iso_P1_be Iso_P2_be data_pos
            global Iso_Pfit_ut Iso_Pfit_et Iso_Pfit_ps Iso_Pfit1_be Iso_Pfit2_be
            
            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')

            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P')

            if app.firstdataButton_iso.Value
                plt_slc(1) = 1;
            else
                plt_slc(1) = 0;
            end
            if app.seconddataButton_iso.Value
                plt_slc(2) = 2;
            else
                plt_slc(2) = 0;
            end
            if app.thirddataButton_iso.Value
                plt_slc(3) = 3;
            else
                plt_slc(3) = 0;
            end
            if app.fourthdataButton_iso.Value
                plt_slc(4) = 4;
            else
                plt_slc(4) = 0;
            end
            if app.fifthdataButton_iso.Value
                plt_slc(5) = 5;
            else
                plt_slc(5) = 0;
            end
            if app.sixthdataButton_iso.Value
                plt_slc(6) = 6;
            else
                plt_slc(6) = 0;
            end
            if app.seventhdataButton_iso.Value
                plt_slc(7) = 7;
            else
                plt_slc(7) = 0;
            end
            if app.eighthdataButton_iso.Value
                plt_slc(8) = 8;
            else
                plt_slc(8) = 0;
            end
            if app.ninethdataButton_iso.Value
                plt_slc(9) = 9;
            else
                plt_slc(9) = 0;
            end
            if app.tenthdataButton_iso.Value
                plt_slc(10) = 10;
            else
                plt_slc(10) = 0;
            end
            type1 = ["o","pentagram","+","square","^",">","hexagram","x","diamond","v","<"];
            type2 = ["*","hexagram","x","diamond","v","<","pentagram","+","square","^",">"];
            col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30","#7E2F8E","#EB9A5C","#0E9E2F","#EB5C9D"];
            col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30","#9E2F8E","#FB9A5C","#6E9E2F","#DB5C9D"];
            for i = plt_slc
                if i ~= 0
                    switch string(data_pos(i,1))
                        case "UT"
                            bnds = cell2mat(data_pos(i,2));
                            bnds_ext = cell2mat(data_pos(i,3));
                            lam_1 = Iso_lam_ut(bnds(1):bnds(2));
                            P_exp1 = Iso_P_ut(bnds(1):bnds(2));
                            lam_1_ext = Iso_lam_ut_ext(bnds_ext(1):bnds_ext(2));
                            P_11 = Iso_Pfit_ut(bnds_ext(1):bnds_ext(2));
                            lam_2 = [];
                            lam_2_ext = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "ET"
                            bnds = cell2mat(data_pos(i,2));
                            bnds_ext = cell2mat(data_pos(i,3));
                            lam_1 = Iso_lam_et(bnds(1):bnds(2));
                            P_exp1 = Iso_P_et(bnds(1):bnds(2));
                            lam_1_ext = Iso_lam_et_ext(bnds_ext(1):bnds_ext(2));
                            P_11 = Iso_Pfit_et(bnds_ext(1):bnds_ext(2));
                            lam_2 = [];
                            lam_2_ext = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "PS"
                            bnds = cell2mat(data_pos(i,2));
                            bnds_ext = cell2mat(data_pos(i,3));
                            lam_1 = Iso_lam_ps(bnds(1):bnds(2));
                            P_exp1 = Iso_P_ps(bnds(1):bnds(2));
                            lam_1_ext = Iso_lam_ps_ext(bnds_ext(1):bnds_ext(2));
                            P_11 = Iso_Pfit_ps(bnds_ext(1):bnds_ext(2));
                            lam_2 = [];
                            lam_2_ext = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "BT"
                            bnds = cell2mat(data_pos(i,2));
                            bnds_ext = cell2mat(data_pos(i,3));
    %                         lam_1 = lam1_be(bnds(1):bnds(2));
    %                         P_exp1 = P1_be(bnds(1):bnds(2));
    %                         P_11 = [];
    %                         lam_2 = lam2_be(bnds(1):bnds(2));
    %                         P_exp2 = P2_be(bnds(1):bnds(2));
    %                         P_22 = Pdata_be(bnds(1):bnds(2));
                            lam_2 = Iso_lam2_be(bnds(1):bnds(2));
                            P_exp2 = Iso_P2_be(bnds(1):bnds(2));
                            lam_2_ext = Iso_lam2_be_ext(bnds_ext(1):bnds_ext(2));
                            P_22 = Iso_Pfit2_be(bnds_ext(1):bnds_ext(2));
                            lam_1 = Iso_lam1_be(bnds(1):bnds(2));
                            P_exp1 = Iso_P1_be(bnds(1):bnds(2));
                            lam_1_ext = Iso_lam1_be_ext(bnds_ext(1):bnds_ext(2));
                            P_11 = Iso_Pfit1_be(bnds_ext(1):bnds_ext(2));
                    end
                    
                    bt_plot(app,lam_1,lam_2,P_exp1,P_exp2,lam_1_ext,lam_2_ext,P_11,P_22,type1(i),type2(i),col1(i),col2(i),i)
                end

            end

            plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')

            legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

        end

        % Value changed function: Switch
        function switch_plot(app, event)
            selected_tab = app.TabGroup.SelectedTab.Title;
            if selected_tab == "Isotropic"
                plot_button_iso(app,event)
            elseif selected_tab == "Anisotropic"
                plot_button(app,event)
            elseif selected_tab == "Others"
                plot_button_oth(app,event)
            end
        end

        % Image clicked function: Image2_2
        function torightpanel(app, event)
            app.GridLayout.RowHeight = {'1x'};
            app.GridLayout.ColumnWidth = {0, '1x'};
        end

        % Image clicked function: Image2_3
        function toleftpanel(app, event)
            app.GridLayout.RowHeight = {'1x'};
            app.GridLayout.ColumnWidth = {734, '1x'};
        end

        % Button pushed function: SelectFileButton_oth_1, 
        % ...and 9 other components
        function getfile_oth(app, event)
            global table_data no_data no_data_old txtfield_oth selectfile_oth typefield_oth
            [file,path] = uigetfile({'*.dat';'*.txt'});

            for i = 1:length(txtfield_oth)
                tag_oth(i) = string(txtfield_oth(i).Tag);
            end

            filenum = find(tag_oth == event.Source.Tag);

            figure(app.HyperDataUIFigure);

            if file == 0
                txtfield_oth(filenum).Value = '';
                return;
            end

            mem = dlmread(strcat(path,file));
            type = typefield_oth(filenum).Value;
            if filenum == 1
                no_data(filenum,1) = 1;
            else
                no_data(filenum,1) = no_data(filenum-1,2)+1;
            end
            if type == "ET"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "UT"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "PS"
                no_data(filenum,2) = no_data(filenum,1)+1;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','P1'}';
            elseif type == "BT"
                no_data(filenum,2) = no_data(filenum,1)+3;
                app.UITable.ColumnName(no_data(filenum,1):no_data(filenum,2)) = {'Stretch1','Stretch2','P1','P2'}';
            end
            sz_dat = size(no_data);
            if isequal(size(no_data_old),sz_dat)
                if not(isequal(no_data, no_data_old))
                    for i = 1:sz_dat(1)
                        type1 = typefield_oth(i).Value;
                        if i == 1
                            no_data(i,1) = 1;
                        else
                            no_data(i,1) = no_data(i-1,2)+1;
                        end
                        if type1 == "ET"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "UT"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "PS"
                            no_data(i,2) = no_data(i,1)+1;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','P1'}';
                        elseif type1 == "BT"
                            no_data(i,2) = no_data(i,1)+3;
                            app.UITable.ColumnName(no_data(i,1):no_data(i,2)) = {'Stretch1','Stretch2','P1','P2'}';
                        end
                        if i ~= filenum
                            table_data(:,no_data(i,1):no_data(i,2)) = app.UITable.Data(:,no_data_old(i,1):no_data_old(i,2));
                        end
                    end
                end
            end
            no_data_old = no_data;
            table_data(1:length(mem),no_data(filenum,1):no_data(filenum,2)) = mem;
            app.UITable.Data = table_data;

            txtfield_oth(filenum).Value = strcat(path,file);

            value = txtfield_oth(filenum).Value;
            if value ~= "" && filenum ~= 10
                txtfield_oth(filenum+1).Enable = 1;
                selectfile_oth(filenum+1).Enable = 1;
                typefield_oth(filenum+1).Enable = 1;
            end
            arrange_plot_buttons_oth(app);
        end

        % Button pushed function: OptimizeButton_oth
        function run_oth(app, event)
            global txtfield_oth typefield_oth
            
            app.OptimizeButton_oth.Enable = 0;
            drawnow;

            selected_model = app.ModelListbox.Value;

            app.Switch.Visible = 0;
            Data_Info = {};
            for i = 1:length(txtfield_oth)
                if txtfield_oth(i).Value ~= ""
                    Data_Info(i,:) = {typefield_oth(i).Value; txtfield_oth(i).Value};
                    if (typefield_oth(i).Value == "BT") > 0
                        app.Switch.Visible = 1;
                        app.Switch.Enable = 1;
                    end
                end
            end           

            global Oth_lam_ut Oth_lam_et Oth_lam_ps Oth_lam1_be Oth_lam2_be
            global Oth_P_ut Oth_P_et Oth_P_ps Oth_P1_be Oth_P2_be data_pos
            global Oth_Pfit_ut Oth_Pfit_et Oth_Pfit_ps Oth_Pfit1_be Oth_Pfit2_be
            main_gui_oth

            Oth_lam_ut = lam_ut;
            Oth_lam_et = lam_et;
            Oth_lam_ps = lam_ps;
            Oth_lam1_be = lam1_be;
            Oth_lam2_be = lam2_be;
            Oth_P_ut = P_ut;
            Oth_P_et = P_et;
            Oth_P_ps = P_ps;
            Oth_P1_be = P1_be;
            Oth_P2_be = P2_be;
            Oth_Pfit_ut = P11_ut;
            Oth_Pfit_et = P11_et;
            Oth_Pfit_ps = P11_ps;
            Oth_Pfit1_be = P11_be1;
            Oth_Pfit2_be = P22_be1;

            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P','Interpreter','Latex')

            type1 = ["o","pentagram","+","square","^",">","hexagram","x","diamond","v","<"];
            type2 = ["*","hexagram","x","diamond","v","<","pentagram","+","square","^",">"];
            col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30","#7E2F8E","#EB9A5C","#0E9E2F","#EB5C9D"];
            col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30","#9E2F8E","#FB9A5C","#6E9E2F","#DB5C9D"];

            qof_tot = [];
            totsz = size(data_pos);
            for i = 1:totsz(1)
                switch string(data_pos(i,1))
                    case "UT"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_ut(bnds(1):bnds(2));
                        P_exp1 = P_ut(bnds(1):bnds(2));
                        P_11 = P11_ut(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);
                    case "ET"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_et(bnds(1):bnds(2));
                        P_exp1 = P_et(bnds(1):bnds(2));
                        P_11 = P11_et(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);
                    case "PS"
                        bnds = cell2mat(data_pos(i,2));
                        lam_1 = lam_ps(bnds(1):bnds(2));
                        P_exp1 = P_ps(bnds(1):bnds(2));
                        P_11 = P11_ps(bnds(1):bnds(2));
                        lam_2 = [];
                        P_exp2 = [];
                        P_22 = [];
                        qof_tot(end+1,:) = qof_function(lam_1, P_11, P_exp1);
                    case "BT"
                        bnds = cell2mat(data_pos(i,2));
                        lam_2 = lam2_be(bnds(1):bnds(2));
                        P_exp2 = P2_be(bnds(1):bnds(2));
                        P_22 = P22_be1(bnds(1):bnds(2));
                        lam_1 = lam1_be(bnds(1):bnds(2));
                        P_exp1 = P1_be(bnds(1):bnds(2));
                        P_11 = P11_be1(bnds(1):bnds(2));
                        qof1 = qof_function(lam_1, P_11, P_exp1);
                        qof2 = qof_function(lam_2, P_22, P_exp2);
                        qof_tot(end+1,:) = qof1+qof2;
                end
                
                bt_plot(app,lam_1,lam_2,P_exp1,P_exp2,lam_1,lam_2,P_11,P_22,type1(i),type2(i),col1(i),col2(i),i)
    
            end
            
            plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')
            
            legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
            
            app.UITable_qof_oth.Data = double(qof_tot);
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

            switch selected_model
                case "Ogden model"
                    app.UITable_out.ColumnName = {"a_1"; "a_2"; "a_3"; "mu_1"; "mu_2"; "mu_3"};
                case "Extended eight-chain model"
                    app.UITable_out.ColumnName = {"mu"; "N"; "mu_2"};
                case "Microsphere model"
                    app.UITable_out.ColumnName = {"mu"; "N"; "p"; "U"; "q"};
            end


            app.UITable_out.Data = x_par;
            app.PlotSelectionPanel_oth.Enable = "on";

            app.OptimizeButton_oth.Enable = 1;
        end

        % Button pushed function: PlotButton_oth
        function plot_button_oth(app, event)
            global Oth_lam_ut Oth_lam_et Oth_lam_ps Oth_lam1_be Oth_lam2_be
            global Oth_P_ut Oth_P_et Oth_P_ps Oth_P1_be Oth_P2_be data_pos
            global Oth_Pfit_ut Oth_Pfit_et Oth_Pfit_ps Oth_Pfit1_be Oth_Pfit2_be
            
            cla(app.UIAxes)
            cla(app.UIAxes,"reset")
            hold(app.UIAxes, 'on' )
            grid(app.UIAxes, 'on')

            xlabel(app.UIAxes,'$\lambda$','Interpreter','Latex')
            ylabel(app.UIAxes,'P')

            if app.firstdataButton_oth.Value
                plt_slc(1) = 1;
            else
                plt_slc(1) = 0;
            end
            if app.seconddataButton_oth.Value
                plt_slc(2) = 2;
            else
                plt_slc(2) = 0;
            end
            if app.thirddataButton_oth.Value
                plt_slc(3) = 3;
            else
                plt_slc(3) = 0;
            end
            if app.fourthdataButton_oth.Value
                plt_slc(4) = 4;
            else
                plt_slc(4) = 0;
            end
            if app.fifthdataButton_oth.Value
                plt_slc(5) = 5;
            else
                plt_slc(5) = 0;
            end
            if app.sixthdataButton_oth.Value
                plt_slc(6) = 6;
            else
                plt_slc(6) = 0;
            end
            if app.seventhdataButton_oth.Value
                plt_slc(7) = 7;
            else
                plt_slc(7) = 0;
            end
            if app.eighthdataButton_oth.Value
                plt_slc(8) = 8;
            else
                plt_slc(8) = 0;
            end
            if app.ninethdataButton_oth.Value
                plt_slc(9) = 9;
            else
                plt_slc(9) = 0;
            end
            if app.tenthdataButton_oth.Value
                plt_slc(10) = 10;
            else
                plt_slc(10) = 0;
            end
            type1 = ["o","pentagram","+","square","^",">","hexagram","x","diamond","v","<"];
            type2 = ["*","hexagram","x","diamond","v","<","pentagram","+","square","^",">"];
            col1 = ["#0072BD","#EDB120","#A2142F","#4DBEEE","#D95319","#77AC30","#7E2F8E","#EB9A5C","#0E9E2F","#EB5C9D"];
            col2 = ["#5072BD","#FDB120","#B2142F","#4DCEEE","#F95319","#97AC30","#9E2F8E","#FB9A5C","#6E9E2F","#DB5C9D"];
            for i = plt_slc
                if i ~= 0
                    switch string(data_pos(i,1))
                        case "UT"
                            bnds = cell2mat(data_pos(i,2));
                            lam_1 = Oth_lam_ut(bnds(1):bnds(2));
                            P_exp1 = Oth_P_ut(bnds(1):bnds(2));
                            P_11 = Oth_Pfit_ut(bnds(1):bnds(2));
                            lam_2 = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "ET"
                            bnds = cell2mat(data_pos(i,2));
                            lam_1 = Oth_lam_et(bnds(1):bnds(2));
                            P_exp1 = Oth_P_et(bnds(1):bnds(2));
                            P_11 = Oth_Pfit_et(bnds(1):bnds(2));
                            lam_2 = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "PS"
                            bnds = cell2mat(data_pos(i,2));
                            lam_1 = Oth_lam_ps(bnds(1):bnds(2));
                            P_exp1 = Oth_P_ps(bnds(1):bnds(2));
                            P_11 = Oth_Pfit_ps(bnds(1):bnds(2));
                            lam_2 = [];
                            P_exp2 = [];
                            P_22 = [];
                        case "BT"
                            bnds = cell2mat(data_pos(i,2));
                            lam_2 = Oth_lam2_be(bnds(1):bnds(2));
                            P_exp2 = Oth_P2_be(bnds(1):bnds(2));
                            P_22 = Oth_Pfit2_be(bnds(1):bnds(2));
                            lam_1 = Oth_lam1_be(bnds(1):bnds(2));
                            P_exp1 = Oth_P1_be(bnds(1):bnds(2));
                            P_11 = Oth_Pfit1_be(bnds(1):bnds(2));
                    end
                    
                    bt_plot(app,lam_1,lam_2,P_exp1,P_exp2,lam_1,lam_2,P_11,P_22,type1(i),type2(i),col1(i),col2(i),i)
                end

            end

            plot(app.UIAxes,nan,nan,'k', 'LineWidth', 1.5, 'DisplayName', 'Hyperdata fit')

            legend(app.UIAxes,'Location', 'NorthWest','Interpreter','Latex')
            
            set(findall(app.UIAxes,'-property','FontSize'),'FontSize',18)
            set(findall(app.UIAxes,'-property','Interpreter'),'Interpreter','Latex')

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HyperDataUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {972, 972};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {734, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HyperDataUIFigure = uifigure('Visible', 'off');
            app.HyperDataUIFigure.Name = 'HyperData';
            app.HyperDataUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'icon.png');
            app.HyperDataUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HyperDataUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Tooltip = {''};
            app.GridLayout15 = uigridlayout(app.LeftPanel);
            app.DataTrainingSoftwareforAnisotropicHyperelasticMaterialsLabel = uilabel(app.GridLayout15);
            app.DataTrainingSoftwareforAnisotropicHyperelasticMaterialsLabel.Tooltip = {'Hyper-Data'; 'Copyright (C) 2023 Hüsnü Dal'; 'GNU GPL v3.0'};
            app.DataTrainingSoftwareforAnisotropicHyperelasticMaterialsLabel.Text = 'Data-Training Software for (An)isotropic Hyperelastic Materials';
            app.Image2_2 = uiimage(app.GridLayout15);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @torightpanel, true);
            app.Image2_2.Tooltip = {'Expand or go to the right panel.'};
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'images', 'right_arrow.png');
            app.Image2 = uiimage(app.GridLayout15);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @reset_bt, true);
            app.Image2.Tooltip = {'Restart the software'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'reset_ico.png');
            app.TabGroup = uitabgroup(app.GridLayout15);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @isotropyselect, true);
            app.AnisotropicTab = uitab(app.TabGroup);
            app.AnisotropicTab.Title = 'Anisotropic';
            app.GridLayout14 = uigridlayout(app.AnisotropicTab);
            app.TypeofexperimentDropDownLabel = uilabel(app.GridLayout14);
            app.TypeofexperimentDropDownLabel.Text = 'Type of experiment';
            app.TypeofexperimentDropDown = uidropdown(app.GridLayout14);
            app.TypeofexperimentDropDown.Items = {'ET', 'UT', 'TS', 'BT'};
            app.TypeofexperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @typeexp, true);
            app.TypeofexperimentDropDown.Value = 'ET';
            app.InputfilesPanel = uipanel(app.GridLayout14);
            app.InputfilesPanel.Title = ' Input files';
            app.GridLayout2 = uigridlayout(app.InputfilesPanel);
            app.fsLabel = uilabel(app.GridLayout2);
            app.fsLabel.Visible = 'off';
            app.fsLabel.Text = 'fs';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.Editable = 'off';
            app.SelectfileButton = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @get_file1, true);
            app.SelectfileButton.Text = 'Select file';
            app.sfLabel = uilabel(app.GridLayout2);
            app.sfLabel.Visible = 'off';
            app.sfLabel.Text = 'sf';
            app.EditField_2 = uieditfield(app.GridLayout2, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.SelectfileButton_2 = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @get_file2, true);
            app.SelectfileButton_2.Enable = 'off';
            app.SelectfileButton_2.Text = 'Select file';
            app.fnLabel = uilabel(app.GridLayout2);
            app.fnLabel.Visible = 'off';
            app.fnLabel.Text = 'fn';
            app.EditField_3 = uieditfield(app.GridLayout2, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.SelectfileButton_3 = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton_3.ButtonPushedFcn = createCallbackFcn(app, @get_file3, true);
            app.SelectfileButton_3.Enable = 'off';
            app.SelectfileButton_3.Text = 'Select file';
            app.nfLabel = uilabel(app.GridLayout2);
            app.nfLabel.Visible = 'off';
            app.nfLabel.Text = 'nf';
            app.EditField_4 = uieditfield(app.GridLayout2, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Enable = 'off';
            app.SelectfileButton_4 = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton_4.ButtonPushedFcn = createCallbackFcn(app, @get_file4, true);
            app.SelectfileButton_4.Enable = 'off';
            app.SelectfileButton_4.Text = 'Select file';
            app.snLabel = uilabel(app.GridLayout2);
            app.snLabel.Visible = 'off';
            app.snLabel.Text = 'sn';
            app.EditField_5 = uieditfield(app.GridLayout2, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Enable = 'off';
            app.SelectfileButton_5 = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton_5.ButtonPushedFcn = createCallbackFcn(app, @get_file5, true);
            app.SelectfileButton_5.Enable = 'off';
            app.SelectfileButton_5.Text = 'Select file';
            app.nsLabel = uilabel(app.GridLayout2);
            app.nsLabel.Visible = 'off';
            app.nsLabel.Text = 'ns';
            app.EditField_6 = uieditfield(app.GridLayout2, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Enable = 'off';
            app.SelectfileButton_6 = uibutton(app.GridLayout2, 'push');
            app.SelectfileButton_6.ButtonPushedFcn = createCallbackFcn(app, @get_file6, true);
            app.SelectfileButton_6.Enable = 'off';
            app.SelectfileButton_6.Text = 'Select file';
            app.SystemparametersPanel = uipanel(app.GridLayout14);
            app.SystemparametersPanel.Title = 'System parameters';
            app.GridLayout3 = uigridlayout(app.SystemparametersPanel);
            app.NumberofcontrolpointsSpinnerLabel = uilabel(app.GridLayout3);
            app.NumberofcontrolpointsSpinnerLabel.Text = 'Number of control points';
            app.NumberofcontrolpointsSpinner = uispinner(app.GridLayout3);
            app.NumberofcontrolpointsSpinner.Limits = [1 Inf];
            app.NumberofcontrolpointsSpinner.Value = 1;
            app.DegreeofBsplinesSpinnerLabel = uilabel(app.GridLayout3);
            app.DegreeofBsplinesSpinnerLabel.Text = 'Degree of B-splines';
            app.DegreeofBsplinesSpinner = uispinner(app.GridLayout3);
            app.DegreeofBsplinesSpinner.Limits = [1 Inf];
            app.DegreeofBsplinesSpinner.Value = 1;
            app.NumberoffreeenergyfunctionsSpinnerLabel = uilabel(app.GridLayout3);
            app.NumberoffreeenergyfunctionsSpinnerLabel.Text = 'Number of free energy functions';
            app.NumberoffreeenergyfunctionsSpinner = uispinner(app.GridLayout3);
            app.NumberoffreeenergyfunctionsSpinner.Limits = [2 4];
            app.NumberoffreeenergyfunctionsSpinner.ValueChangedFcn = createCallbackFcn(app, @n_free_select, true);
            app.NumberoffreeenergyfunctionsSpinner.Value = 2;
            app.DispersionmodelPanel = uipanel(app.GridLayout14);
            app.DispersionmodelPanel.Title = '  Dispersion model |                    ';
            app.GridLayout4 = uigridlayout(app.DispersionmodelPanel);
            app.kappa_1Label = uilabel(app.GridLayout4);
            app.kappa_1Label.Interpreter = 'latex';
            app.kappa_1Label.Text = '$\kappa_1$';
            app.kappa_1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.kappa_1EditField.Limits = [0 0.33333];
            app.kappa_1EditField.Tooltip = {'[0,0.3333]'};
            app.kappa_ipLabel = uilabel(app.GridLayout4);
            app.kappa_ipLabel.Interpreter = 'latex';
            app.kappa_ipLabel.Enable = 'off';
            app.kappa_ipLabel.Text = '$\kappa_{ip}$';
            app.kappa_ipEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kappa_ipEditField.Limits = [0 1];
            app.kappa_ipEditField.Enable = 'off';
            app.kappa_ipEditField.Tooltip = {'[0, 1]'};
            app.kappa_2Label = uilabel(app.GridLayout4);
            app.kappa_2Label.Interpreter = 'latex';
            app.kappa_2Label.Enable = 'off';
            app.kappa_2Label.Text = '$\kappa_2$';
            app.kappa_2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.kappa_2EditField.Limits = [0 0.33333];
            app.kappa_2EditField.Enable = 'off';
            app.kappa_2EditField.Tooltip = {'[0,0.3333]'};
            app.kappa_opLabel = uilabel(app.GridLayout4);
            app.kappa_opLabel.Interpreter = 'latex';
            app.kappa_opLabel.Enable = 'off';
            app.kappa_opLabel.Text = '$\kappa_{op}$';
            app.kappa_opEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kappa_opEditField.Limits = [0 0.5];
            app.kappa_opEditField.Enable = 'off';
            app.kappa_opEditField.Tooltip = {'[0, 0.5]'};
            app.kappa_3Label = uilabel(app.GridLayout4);
            app.kappa_3Label.Interpreter = 'latex';
            app.kappa_3Label.Enable = 'off';
            app.kappa_3Label.Text = '$\kappa_3$';
            app.kappa_3EditField = uieditfield(app.GridLayout4, 'numeric');
            app.kappa_3EditField.Limits = [0 0.33333];
            app.kappa_3EditField.Enable = 'off';
            app.kappa_3EditField.Tooltip = {'[0,0.3333]'};
            app.DispersionModelButtonGroup = uibuttongroup(app.GridLayout14);
            app.DispersionModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @disp_mod_sel, true);
            app.fsButton = uiradiobutton(app.DispersionModelButtonGroup);
            app.fsButton.Text = 'fs';
            app.fsButton.Value = true;
            app.ioButton = uiradiobutton(app.DispersionModelButtonGroup);
            app.ioButton.Text = 'io';
            app.TensionOnlyPanel = uipanel(app.GridLayout14);
            app.GridLayout10 = uigridlayout(app.TensionOnlyPanel);
            app.TensiononlyconditionSwitchLabel = uilabel(app.GridLayout10);
            app.TensiononlyconditionSwitchLabel.Text = 'Tension only condition';
            app.TensiononlyconditionSwitch = uiswitch(app.GridLayout10, 'rocker');
            app.TensiononlyconditionSwitch.Value = 'On';
            app.FiberdirectionsPanel = uipanel(app.GridLayout14);
            app.FiberdirectionsPanel.Title = '   Fiber directions';
            app.GridLayout5 = uigridlayout(app.FiberdirectionsPanel);
            app.Directionoffiber1EditFieldLabel = uilabel(app.GridLayout5);
            app.Directionoffiber1EditFieldLabel.Text = 'Direction of fiber 1';
            app.Directionoffiber1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber1EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber1EditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.Directionoffiber2EditFieldLabel = uilabel(app.GridLayout5);
            app.Directionoffiber2EditFieldLabel.Enable = 'off';
            app.Directionoffiber2EditFieldLabel.Text = 'Direction of fiber 2';
            app.Directionoffiber2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Directionoffiber2EditField.Enable = 'off';
            app.DirectionofFiber2EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber2EditField_2.Enable = 'off';
            app.DirectionofFiber2EditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber2EditField_3.Enable = 'off';
            app.Directionoffiber3EditFieldLabel = uilabel(app.GridLayout5);
            app.Directionoffiber3EditFieldLabel.Enable = 'off';
            app.Directionoffiber3EditFieldLabel.Text = 'Direction of fiber 3';
            app.Directionoffiber3EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Directionoffiber3EditField.Enable = 'off';
            app.DirectionofFiber3EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber3EditField_2.Enable = 'off';
            app.DirectionofFiber3EditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.DirectionofFiber3EditField_3.Enable = 'off';
            app.Image_2 = uiimage(app.GridLayout14);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @reset_optimize, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'metu_logo_borderless.png');
            app.PlotselectionPanel = uipanel(app.GridLayout14);
            app.PlotselectionPanel.Enable = 'off';
            app.PlotselectionPanel.Title = 'Plot selection';
            app.GridLayout8 = uigridlayout(app.PlotselectionPanel);
            app.firstdataButton = uibutton(app.GridLayout8, 'state');
            app.firstdataButton.Text = '1st data';
            app.seconddataButton = uibutton(app.GridLayout8, 'state');
            app.seconddataButton.Text = ' 2nd data';
            app.thirddataButton = uibutton(app.GridLayout8, 'state');
            app.thirddataButton.Enable = 'off';
            app.thirddataButton.Text = ' 3rd data';
            app.fourthdataButton = uibutton(app.GridLayout8, 'state');
            app.fourthdataButton.Enable = 'off';
            app.fourthdataButton.Text = ' 4th data';
            app.fifthdataButton = uibutton(app.GridLayout8, 'state');
            app.fifthdataButton.Enable = 'off';
            app.fifthdataButton.Text = ' 5th data';
            app.sixthdataButton = uibutton(app.GridLayout8, 'state');
            app.sixthdataButton.Enable = 'off';
            app.sixthdataButton.Text = ' 6th data';
            app.PlotButton = uibutton(app.GridLayout8, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot_button, true);
            app.PlotButton.Text = 'Plot';
            app.OptimizeButton = uibutton(app.GridLayout14, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.OptimizeButton.Text = 'Optimize';
            app.QualityoffitPanel = uipanel(app.GridLayout14);
            app.QualityoffitPanel.Title = 'Quality of fit';
            app.GridLayout6 = uigridlayout(app.QualityoffitPanel);
            app.UITable_qof = uitable(app.GridLayout6);
            app.UITable_qof.ColumnName = {'Overall'; 'First region'; 'Second region'; 'Third region'};
            app.UITable_qof.RowName = {'All data'; 'Data1'; 'Data2'; 'Data3'; 'Data4'; 'Data5'; 'Data6'};
            app.IsotropicTab = uitab(app.TabGroup);
            app.IsotropicTab.Title = 'Isotropic';
            app.GridLayout16 = uigridlayout(app.IsotropicTab);
            app.InputFilesPanel_iso = uipanel(app.GridLayout16);
            app.InputFilesPanel_iso.Title = ' Input files';
            app.GridLayout2_2 = uigridlayout(app.InputFilesPanel_iso);
            app.FileType_iso_1 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_1.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_1.Tag = 'fl_iso_1';
            app.FileType_iso_1.Value = 'UT';
            app.EditField_iso_1 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_1.Tag = 'fl_iso_1';
            app.EditField_iso_1.Editable = 'off';
            app.SelectFileButton_iso_1 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_1.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_1.Tag = 'fl_iso_1';
            app.SelectFileButton_iso_1.Text = 'Select file';
            app.FileType_iso_2 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_2.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_2.Tag = 'fl_iso_2';
            app.FileType_iso_2.Enable = 'off';
            app.FileType_iso_2.Value = 'UT';
            app.EditField_iso_2 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_2.Tag = 'fl_iso_2';
            app.EditField_iso_2.Editable = 'off';
            app.EditField_iso_2.Enable = 'off';
            app.SelectFileButton_iso_2 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_2.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_2.Tag = 'fl_iso_2';
            app.SelectFileButton_iso_2.Enable = 'off';
            app.SelectFileButton_iso_2.Text = 'Select file';
            app.FileType_iso_3 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_3.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_3.Tag = 'fl_iso_3';
            app.FileType_iso_3.Enable = 'off';
            app.FileType_iso_3.Value = 'UT';
            app.EditField_iso_3 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_3.Tag = 'fl_iso_3';
            app.EditField_iso_3.Editable = 'off';
            app.EditField_iso_3.Enable = 'off';
            app.SelectFileButton_iso_3 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_3.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_3.Tag = 'fl_iso_3';
            app.SelectFileButton_iso_3.Enable = 'off';
            app.SelectFileButton_iso_3.Text = 'Select file';
            app.FileType_iso_4 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_4.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_4.Tag = 'fl_iso_4';
            app.FileType_iso_4.Enable = 'off';
            app.FileType_iso_4.Value = 'UT';
            app.EditField_iso_4 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_4.Tag = 'fl_iso_4';
            app.EditField_iso_4.Editable = 'off';
            app.EditField_iso_4.Enable = 'off';
            app.SelectFileButton_iso_4 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_4.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_4.Tag = 'fl_iso_4';
            app.SelectFileButton_iso_4.Enable = 'off';
            app.SelectFileButton_iso_4.Text = 'Select file';
            app.FileType_iso_5 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_5.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_5.Tag = 'fl_iso_5';
            app.FileType_iso_5.Enable = 'off';
            app.FileType_iso_5.Value = 'UT';
            app.EditField_iso_5 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_5.Tag = 'fl_iso_5';
            app.EditField_iso_5.Editable = 'off';
            app.EditField_iso_5.Enable = 'off';
            app.SelectFileButton_iso_5 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_5.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_5.Tag = 'fl_iso_5';
            app.SelectFileButton_iso_5.Enable = 'off';
            app.SelectFileButton_iso_5.Text = 'Select file';
            app.FileType_iso_6 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_6.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_6.Tag = 'fl_iso_6';
            app.FileType_iso_6.Enable = 'off';
            app.FileType_iso_6.Value = 'UT';
            app.EditField_iso_6 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_6.Tag = 'fl_iso_6';
            app.EditField_iso_6.Editable = 'off';
            app.EditField_iso_6.Enable = 'off';
            app.SelectFileButton_iso_6 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_6.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_6.Tag = 'fl_iso_6';
            app.SelectFileButton_iso_6.Enable = 'off';
            app.SelectFileButton_iso_6.Text = 'Select file';
            app.FileType_iso_7 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_7.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_7.Tag = 'fl_iso_7';
            app.FileType_iso_7.Enable = 'off';
            app.FileType_iso_7.Value = 'UT';
            app.EditField_iso_7 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_7.Tag = 'fl_iso_7';
            app.EditField_iso_7.Editable = 'off';
            app.EditField_iso_7.Enable = 'off';
            app.SelectFileButton_iso_7 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_7.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_7.Tag = 'fl_iso_7';
            app.SelectFileButton_iso_7.Enable = 'off';
            app.SelectFileButton_iso_7.Text = 'Select file';
            app.FileType_iso_8 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_8.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_8.Tag = 'fl_iso_8';
            app.FileType_iso_8.Enable = 'off';
            app.FileType_iso_8.Value = 'UT';
            app.EditField_iso_8 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_8.Tag = 'fl_iso_8';
            app.EditField_iso_8.Editable = 'off';
            app.EditField_iso_8.Enable = 'off';
            app.SelectFileButton_iso_8 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_8.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_8.Tag = 'fl_iso_8';
            app.SelectFileButton_iso_8.Enable = 'off';
            app.SelectFileButton_iso_8.Text = 'Select file';
            app.FileType_iso_9 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_9.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_9.Tag = 'fl_iso_9';
            app.FileType_iso_9.Enable = 'off';
            app.FileType_iso_9.Value = 'UT';
            app.EditField_iso_9 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_9.Tag = 'fl_iso_9';
            app.EditField_iso_9.Editable = 'off';
            app.EditField_iso_9.Enable = 'off';
            app.SelectFileButton_iso_9 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_9.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_9.Tag = 'fl_iso_9';
            app.SelectFileButton_iso_9.Enable = 'off';
            app.SelectFileButton_iso_9.Text = 'Select file';
            app.FileType_iso_10 = uidropdown(app.GridLayout2_2);
            app.FileType_iso_10.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_iso_10.Tag = 'fl_iso_10';
            app.FileType_iso_10.Enable = 'off';
            app.FileType_iso_10.Value = 'UT';
            app.EditField_iso_10 = uieditfield(app.GridLayout2_2, 'text');
            app.EditField_iso_10.Tag = 'fl_iso_10';
            app.EditField_iso_10.Editable = 'off';
            app.EditField_iso_10.Enable = 'off';
            app.SelectFileButton_iso_10 = uibutton(app.GridLayout2_2, 'push');
            app.SelectFileButton_iso_10.ButtonPushedFcn = createCallbackFcn(app, @getfile_iso, true);
            app.SelectFileButton_iso_10.Tag = 'fl_iso_10';
            app.SelectFileButton_iso_10.Enable = 'off';
            app.SelectFileButton_iso_10.Text = 'Select file';
            app.SystemParametersPanel_iso = uipanel(app.GridLayout16);
            app.SystemParametersPanel_iso.Title = 'System parameters';
            app.GridLayout3_2 = uigridlayout(app.SystemParametersPanel_iso);
            app.NumberofcontrolpointsLabel = uilabel(app.GridLayout3_2);
            app.NumberofcontrolpointsLabel.Text = 'Number of control points';
            app.NumberofControlPointsSpinner_iso = uispinner(app.GridLayout3_2);
            app.NumberofControlPointsSpinner_iso.Limits = [1 Inf];
            app.NumberofControlPointsSpinner_iso.Value = 1;
            app.DegreeofBsplinesSpinner_2Label = uilabel(app.GridLayout3_2);
            app.DegreeofBsplinesSpinner_2Label.Text = 'Degree of B-splines';
            app.DegreeofBsplinesSpinner_iso = uispinner(app.GridLayout3_2);
            app.DegreeofBsplinesSpinner_iso.Limits = [1 Inf];
            app.DegreeofBsplinesSpinner_iso.Value = 1;
            app.NumberoffreeenergyfunctionsLabel = uilabel(app.GridLayout3_2);
            app.NumberoffreeenergyfunctionsLabel.Enable = 'off';
            app.NumberoffreeenergyfunctionsLabel.Text = 'Number of free energy functions';
            app.NumberofFreeEnergyFunctionsSpinner_iso = uispinner(app.GridLayout3_2);
            app.NumberofFreeEnergyFunctionsSpinner_iso.Limits = [2 4];
            app.NumberofFreeEnergyFunctionsSpinner_iso.Enable = 'off';
            app.NumberofFreeEnergyFunctionsSpinner_iso.Value = 2;
            app.Image_iso = uiimage(app.GridLayout16);
            app.Image_iso.ImageClickedFcn = createCallbackFcn(app, @reset_optimize, true);
            app.Image_iso.ImageSource = fullfile(pathToMLAPP, 'images', 'metu_logo_borderless.png');
            app.PlotSelectionPanel_iso = uipanel(app.GridLayout16);
            app.PlotSelectionPanel_iso.Enable = 'off';
            app.PlotSelectionPanel_iso.Title = 'Plot selection';
            app.GridLayout8_2 = uigridlayout(app.PlotSelectionPanel_iso);
            app.GridLayout11 = uigridlayout(app.GridLayout8_2);
            app.firstdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.firstdataButton_iso.Enable = 'off';
            app.firstdataButton_iso.Text = '1st data';
            app.sixthdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.sixthdataButton_iso.Enable = 'off';
            app.sixthdataButton_iso.Text = ' 6th data';
            app.seconddataButton_iso = uibutton(app.GridLayout11, 'state');
            app.seconddataButton_iso.Enable = 'off';
            app.seconddataButton_iso.Text = ' 2nd data';
            app.seventhdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.seventhdataButton_iso.Enable = 'off';
            app.seventhdataButton_iso.Text = ' 7th data';
            app.thirddataButton_iso = uibutton(app.GridLayout11, 'state');
            app.thirddataButton_iso.Enable = 'off';
            app.thirddataButton_iso.Text = ' 3rd data';
            app.eighthdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.eighthdataButton_iso.Enable = 'off';
            app.eighthdataButton_iso.Text = ' 8th data';
            app.fourthdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.fourthdataButton_iso.Enable = 'off';
            app.fourthdataButton_iso.Text = ' 4th data';
            app.ninethdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.ninethdataButton_iso.Enable = 'off';
            app.ninethdataButton_iso.Text = ' 9th data';
            app.fifthdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.fifthdataButton_iso.Enable = 'off';
            app.fifthdataButton_iso.Text = ' 5th data';
            app.tenthdataButton_iso = uibutton(app.GridLayout11, 'state');
            app.tenthdataButton_iso.Enable = 'off';
            app.tenthdataButton_iso.Text = ' 10th data';
            app.PlotButton_iso = uibutton(app.GridLayout8_2, 'push');
            app.PlotButton_iso.ButtonPushedFcn = createCallbackFcn(app, @plot_button_iso, true);
            app.PlotButton_iso.Text = 'Plot';
            app.OptimizeButton_iso = uibutton(app.GridLayout16, 'push');
            app.OptimizeButton_iso.ButtonPushedFcn = createCallbackFcn(app, @run_iso, true);
            app.OptimizeButton_iso.Text = 'Optimize';
            app.QualityofFitPanel_iso = uipanel(app.GridLayout16);
            app.QualityofFitPanel_iso.Title = 'Quality of fit';
            app.GridLayout5_2 = uigridlayout(app.QualityofFitPanel_iso);
            app.UITable_qof_iso = uitable(app.GridLayout5_2);
            app.UITable_qof_iso.ColumnName = {'Overall'; 'First region'; 'Second region'; 'Third region'};
            app.UITable_qof_iso.RowName = {'All data'; 'Data1'; 'Data2'; 'Data3'; 'Data4'; 'Data5'; 'Data6'; 'Data7'; 'Data8'; 'Data9'; 'Data'};
            app.Panel = uipanel(app.GridLayout16);
            app.GridLayout19 = uigridlayout(app.Panel);
            app.PolyconvexityofsecondinvariantLabel = uilabel(app.GridLayout19);
            app.PolyconvexityofsecondinvariantLabel.Text = 'Polyconvexity of second invariant';
            app.PolyconvexitySwitch = uiswitch(app.GridLayout19, 'rocker');
            app.PolyconvexitySwitch.Value = 'On';
            app.FormulationtypePanel = uipanel(app.GridLayout16);
            app.FormulationtypePanel.Title = 'Formulation type';
            app.GridLayout12 = uigridlayout(app.FormulationtypePanel);
            app.FormulationTypeListBox = uilistbox(app.GridLayout12);
            app.FormulationTypeListBox.Items = {'Invariant-based', 'Modified invariant-based', 'Principal stretch-based'};
            app.FormulationTypeListBox.Value = 'Invariant-based';
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.GridLayout18 = uigridlayout(app.OthersTab);
            app.SelectmodelPanel = uipanel(app.GridLayout18);
            app.SelectmodelPanel.Title = 'Select model';
            app.GridLayout12_2 = uigridlayout(app.SelectmodelPanel);
            app.ModelListbox = uilistbox(app.GridLayout12_2);
            app.ModelListbox.Items = {'Ogden model', 'Extended eight-chain model', 'Microsphere model'};
            app.ModelListbox.Value = 'Ogden model';
            app.InputFilesPanel_oth = uipanel(app.GridLayout18);
            app.InputFilesPanel_oth.Title = ' Input files';
            app.GridLayout2_3 = uigridlayout(app.InputFilesPanel_oth);
            app.FileType_oth_1 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_1.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_1.Tag = 'fl_oth_1';
            app.FileType_oth_1.Value = 'UT';
            app.EditField_oth_1 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_1.Tag = 'fl_oth_1';
            app.EditField_oth_1.Editable = 'off';
            app.SelectFileButton_oth_1 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_1.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_1.Tag = 'fl_oth_1';
            app.SelectFileButton_oth_1.Text = 'Select file';
            app.FileType_oth_2 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_2.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_2.Tag = 'fl_oth_2';
            app.FileType_oth_2.Enable = 'off';
            app.FileType_oth_2.Value = 'UT';
            app.EditField_oth_2 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_2.Tag = 'fl_oth_2';
            app.EditField_oth_2.Editable = 'off';
            app.EditField_oth_2.Enable = 'off';
            app.SelectFileButton_oth_2 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_2.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_2.Tag = 'fl_oth_2';
            app.SelectFileButton_oth_2.Enable = 'off';
            app.SelectFileButton_oth_2.Text = 'Select file';
            app.FileType_oth_3 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_3.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_3.Tag = 'fl_oth_3';
            app.FileType_oth_3.Enable = 'off';
            app.FileType_oth_3.Value = 'UT';
            app.EditField_oth_3 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_3.Tag = 'fl_oth_3';
            app.EditField_oth_3.Editable = 'off';
            app.EditField_oth_3.Enable = 'off';
            app.SelectFileButton_oth_3 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_3.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_3.Tag = 'fl_oth_3';
            app.SelectFileButton_oth_3.Enable = 'off';
            app.SelectFileButton_oth_3.Text = 'Select file';
            app.FileType_oth_4 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_4.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_4.Tag = 'fl_oth_4';
            app.FileType_oth_4.Enable = 'off';
            app.FileType_oth_4.Value = 'UT';
            app.EditField_oth_4 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_4.Tag = 'fl_oth_4';
            app.EditField_oth_4.Editable = 'off';
            app.EditField_oth_4.Enable = 'off';
            app.SelectFileButton_oth_4 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_4.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_4.Tag = 'fl_oth_4';
            app.SelectFileButton_oth_4.Enable = 'off';
            app.SelectFileButton_oth_4.Text = 'Select file';
            app.FileType_oth_5 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_5.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_5.Tag = ',';
            app.FileType_oth_5.Enable = 'off';
            app.FileType_oth_5.Value = 'UT';
            app.EditField_oth_5 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_5.Tag = 'fl_oth_5';
            app.EditField_oth_5.Editable = 'off';
            app.EditField_oth_5.Enable = 'off';
            app.SelectFileButton_oth_5 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_5.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_5.Tag = 'fl_oth_5';
            app.SelectFileButton_oth_5.Enable = 'off';
            app.SelectFileButton_oth_5.Text = 'Select file';
            app.FileType_oth_6 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_6.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_6.Tag = 'fl_oth_6';
            app.FileType_oth_6.Enable = 'off';
            app.FileType_oth_6.Value = 'UT';
            app.EditField_oth_6 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_6.Tag = 'fl_oth_6';
            app.EditField_oth_6.Editable = 'off';
            app.EditField_oth_6.Enable = 'off';
            app.SelectFileButton_oth_6 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_6.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_6.Tag = 'fl_oth_6';
            app.SelectFileButton_oth_6.Enable = 'off';
            app.SelectFileButton_oth_6.Text = 'Select file';
            app.FileType_oth_7 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_7.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_7.Tag = 'fl_oth_7';
            app.FileType_oth_7.Enable = 'off';
            app.FileType_oth_7.Value = 'UT';
            app.EditField_oth_7 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_7.Tag = 'fl_oth_7';
            app.EditField_oth_7.Editable = 'off';
            app.EditField_oth_7.Enable = 'off';
            app.SelectFileButton_oth_7 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_7.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_7.Tag = 'fl_oth_7';
            app.SelectFileButton_oth_7.Enable = 'off';
            app.SelectFileButton_oth_7.Text = 'Select file';
            app.FileType_oth_8 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_8.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_8.Tag = 'fl_oth_8';
            app.FileType_oth_8.Enable = 'off';
            app.FileType_oth_8.Value = 'UT';
            app.EditField_oth_8 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_8.Tag = 'fl_oth_8';
            app.EditField_oth_8.Editable = 'off';
            app.EditField_oth_8.Enable = 'off';
            app.SelectFileButton_oth_8 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_8.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_8.Tag = 'fl_oth_8';
            app.SelectFileButton_oth_8.Enable = 'off';
            app.SelectFileButton_oth_8.Text = 'Select file';
            app.FileType_oth_9 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_9.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_9.Tag = 'fl_oth_9';
            app.FileType_oth_9.Enable = 'off';
            app.FileType_oth_9.Value = 'UT';
            app.EditField_oth_9 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_9.Tag = 'fl_oth_9';
            app.EditField_oth_9.Editable = 'off';
            app.EditField_oth_9.Enable = 'off';
            app.SelectFileButton_oth_9 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_9.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_9.Tag = 'fl_oth_9';
            app.SelectFileButton_oth_9.Enable = 'off';
            app.SelectFileButton_oth_9.Text = 'Select file';
            app.FileType_oth_10 = uidropdown(app.GridLayout2_3);
            app.FileType_oth_10.Items = {'UT', 'ET', 'PS', 'BT'};
            app.FileType_oth_10.Tag = 'fl_oth_10';
            app.FileType_oth_10.Enable = 'off';
            app.FileType_oth_10.Value = 'UT';
            app.EditField_oth_10 = uieditfield(app.GridLayout2_3, 'text');
            app.EditField_oth_10.Tag = 'fl_oth_10';
            app.EditField_oth_10.Editable = 'off';
            app.EditField_oth_10.Enable = 'off';
            app.SelectFileButton_oth_10 = uibutton(app.GridLayout2_3, 'push');
            app.SelectFileButton_oth_10.ButtonPushedFcn = createCallbackFcn(app, @getfile_oth, true);
            app.SelectFileButton_oth_10.Tag = 'fl_oth_10';
            app.SelectFileButton_oth_10.Enable = 'off';
            app.SelectFileButton_oth_10.Text = 'Select file';
            app.TensionOnlyPanel_2 = uipanel(app.GridLayout18);
            app.TensionOnlyPanel_2.Tooltip = {'Not available.'};
            app.TensionOnlyPanel_2.Enable = 'off';
            app.GridLayout10_2 = uigridlayout(app.TensionOnlyPanel_2);
            app.GridLayout10_2.Tooltip = {''};
            app.WeightoptimizationSwitchLabel = uilabel(app.GridLayout10_2);
            app.WeightoptimizationSwitchLabel.Enable = 'off';
            app.WeightoptimizationSwitchLabel.Text = 'Weight optimization';
            app.WeightoptimizationSwitch = uiswitch(app.GridLayout10_2, 'rocker');
            app.WeightoptimizationSwitch.Enable = 'off';
            app.WeightoptimizationSwitch.Value = 'On';
            app.Image_oth = uiimage(app.GridLayout18);
            app.Image_oth.ImageClickedFcn = createCallbackFcn(app, @reset_optimize, true);
            app.Image_oth.ImageSource = fullfile(pathToMLAPP, 'images', 'metu_logo_borderless.png');
            app.OptimizeButton_oth = uibutton(app.GridLayout18, 'push');
            app.OptimizeButton_oth.ButtonPushedFcn = createCallbackFcn(app, @run_oth, true);
            app.OptimizeButton_oth.Text = 'Optimize';
            app.PlotSelectionPanel_oth = uipanel(app.GridLayout18);
            app.PlotSelectionPanel_oth.Enable = 'off';
            app.PlotSelectionPanel_oth.Title = 'Plot selection';
            app.GridLayout8_3 = uigridlayout(app.PlotSelectionPanel_oth);
            app.GridLayout11_2 = uigridlayout(app.GridLayout8_3);
            app.firstdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.firstdataButton_oth.Enable = 'off';
            app.firstdataButton_oth.Text = '1st data';
            app.sixthdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.sixthdataButton_oth.Enable = 'off';
            app.sixthdataButton_oth.Text = ' 6th data';
            app.seconddataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.seconddataButton_oth.Enable = 'off';
            app.seconddataButton_oth.Text = ' 2nd data';
            app.seventhdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.seventhdataButton_oth.Enable = 'off';
            app.seventhdataButton_oth.Text = ' 7th data';
            app.thirddataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.thirddataButton_oth.Enable = 'off';
            app.thirddataButton_oth.Text = ' 3rd data';
            app.eighthdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.eighthdataButton_oth.Enable = 'off';
            app.eighthdataButton_oth.Text = ' 8th data';
            app.fourthdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.fourthdataButton_oth.Enable = 'off';
            app.fourthdataButton_oth.Text = ' 4th data';
            app.ninethdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.ninethdataButton_oth.Enable = 'off';
            app.ninethdataButton_oth.Text = ' 9th data';
            app.fifthdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.fifthdataButton_oth.Enable = 'off';
            app.fifthdataButton_oth.Text = ' 5th data';
            app.tenthdataButton_oth = uibutton(app.GridLayout11_2, 'state');
            app.tenthdataButton_oth.Enable = 'off';
            app.tenthdataButton_oth.Text = ' 10th data';
            app.PlotButton_oth = uibutton(app.GridLayout8_3, 'push');
            app.PlotButton_oth.ButtonPushedFcn = createCallbackFcn(app, @plot_button_oth, true);
            app.PlotButton_oth.Text = 'Plot';
            app.QualityofFitPanel_oth = uipanel(app.GridLayout18);
            app.QualityofFitPanel_oth.Title = 'Quality of fit';
            app.GridLayout5_3 = uigridlayout(app.QualityofFitPanel_oth);
            app.UITable_qof_oth = uitable(app.GridLayout5_3);
            app.UITable_qof_oth.ColumnName = {'Overall'; 'First region'; 'Second region'; 'Third region'};
            app.UITable_qof_oth.RowName = {'All data'; 'Data1'; 'Data2'; 'Data3'; 'Data4'; 'Data5'; 'Data6'; 'Data7'; 'Data8'; 'Data9'; 'Data'};
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Tooltip = {''};
            app.GridLayout17 = uigridlayout(app.RightPanel);
            app.InputdataPanel = uipanel(app.GridLayout17);
            app.InputdataPanel.Title = 'Input data';
            app.GridLayout7 = uigridlayout(app.InputdataPanel);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Stretch'; 'P1'; 'P2'};
            app.UITable.RowName = {};
            app.IdentifiedparametersPanel = uipanel(app.GridLayout17);
            app.IdentifiedparametersPanel.Title = 'Identified parameters';
            app.GridLayout9 = uigridlayout(app.IdentifiedparametersPanel);
            app.UITable_out = uitable(app.GridLayout9);
            app.UITable_out.ColumnName = {'psi_1'; 'psi_4.1'};
            app.UITable_out.RowName = {};
            app.PlotofthefitPanel = uipanel(app.GridLayout17);
            app.PlotofthefitPanel.Title = 'Plot of the fit';
            app.GridLayout13 = uigridlayout(app.PlotofthefitPanel);
            app.UIAxes = uiaxes(app.GridLayout13);
            xlabel(app.UIAxes, 'Stretch')
            ylabel(app.UIAxes, 'P')
            zlabel(app.UIAxes, 'Z')
            app.Image2_3 = uiimage(app.GridLayout17);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @toleftpanel, true);
            app.Image2_3.Tooltip = {'Go to the left panel, or original form.'};
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'images', 'left_arrow.png');
            app.Image_CC = uiimage(app.GridLayout17);
            app.Image_CC.Tooltip = {'Copyright (C) 2023 Hüsnü Dal'; 'GNU GPL v3.0'};
            app.Image_CC.ImageSource = fullfile(pathToMLAPP, 'images', 'copyright.png');
            app.Switch = uiswitch(app.GridLayout17, 'slider');
            app.Switch.Items = {'Stretch 1', 'Stretch 2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @switch_plot, true);
            app.Switch.Visible = 'off';
            app.Switch.Tooltip = {'Select stretch axis for biaxial tests.'};
            app.Switch.Value = 'Stretch 1';
            % Show the figure after all components are created
            app.HyperDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperdata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HyperDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HyperDataUIFigure)
        end
    end
end